classdef Copy_of_Battleship_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BattleshipGUIUIFigure         matlab.ui.Figure
        UpButton                      matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        AttemptsRemainingEditFieldLabel  matlab.ui.control.Label
        AttemptsRemainingEditField    matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
        MissionStatusLabel            matlab.ui.control.Label
        ControlPanelLabel             matlab.ui.control.Label
        ImportantButtonsLabel         matlab.ui.control.Label
        SettingsLabel                 matlab.ui.control.Label
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        MaximumLengthSpinnerLabel     matlab.ui.control.Label
        MaximumLengthSpinner          matlab.ui.control.Spinner
    end


    properties (Access = private)
        board % LED location(s)
        ard % arduino object
        Adi_pins = ["D12", "D11", "D10";
                    "D8", "D7", "D6";
                    "D4", "D3", "D2"] % Adi's configuration
    
        Vincent_pins = ["D2", "D3", "D4";
                        "D7", "D8", "D9";
                        "D11", "D12", "D13"] % Vincent's configuration
        shipLoc
        lightModes = zeros(3) % array to store how LEDs behave when selected
        % 0 = unknown (solid light)
        % 1 = ship (no light)
        % 2 = ship nearby (blink fast)
        % 3 = ship far away (blink slow)
    end

    methods (Access = private)
    
        % overwrite old position with new position
        function updateBoard(app)
            for c = 1:9
                if app.board(c) == 1
                    switch app.lightModes(c)
                        case 0 % normal
                            writeDigitalPin(app.ard, app.Adi_pins(c), 1)
                        case 1 % no light
                            writeDigitalPin(app.ard, app.Adi_pins(c), 0)
                        case 2 % blink fast
                            writeDigitalPin(app.ard, app.Adi_pins(c), 0)
                            pause(0.2)
                            writeDigitalPin(app.ard, app.Adi_pins(c), 1)
                            pause(0.2)
                        case 3
                            writeDigitalPin(app.ard, app.Adi_pins(c), 0)
                            pause(0.4)
                            writeDigitalPin(app.ard, app.Adi_pins(c), 0)
                            pause(0.4)
                    end
                else 
                    writeDigitalPin(app.ard, app.Adi_pins(c), 0)
                end
            end
        end
        
        % generate array of battleships
        function arr = shipLocations(~, NumShips)
            loc = randperm(9, NumShips);
            arr = zeros(3);
            arr(loc) = 1;
        end
        
        % check for adjacent ships
        function bool = Distance(app, row, col)
            bool = false; % innocent until proven guilty
            
            dirs = [0, 1;
                    1, 0;
                   -1, 0;
                    0,-1];
            
            for i = 1:1:4
                if row + dirs(i, 1) <= 3 && row + dirs(i, 1) >= 1
                    if col + dirs(i, 2) <= 3 && col + dirs(i, 2) >= 1
                        % this is within the bounds of the array
                        if app.shipLoc( [row, col] + dirs(i, :) ) == 1
                            % there is a ship here
                            bool = true;
                            break
                        end
                    end
                end
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            uiresume % resumes program
            [row, col] = find(app.board == 1);
            if row - 1 < 1
                % move not allowed - do nothing
            else
                app.board(row - 1, col) = 1; % update new LED
                app.board(row, col) = 0; % erase old LED
                updateBoard(app)
            end
            uiwait % pauses program
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            uiresume % resumes program
            [row, col] = find(app.board == 1);
            if col + 1 > 3
                % move not allowed - do nothing
            else
                app.board(row, col + 1) = 1; % update new LED
                app.board(row, col) = 0; % erase old LED
                updateBoard(app)
            end
            uiwait % pauses program
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            uiresume % resumes program
            [row, col] = find(app.board == 1);
            if row + 1 > 3
                % move not allowed - do nothing
            else
                app.board(row + 1, col) = 1; % update new LED
                app.board(row, col) = 0; % erase old LED
                updateBoard(app)
            end
            uiwait % pauses program
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            uiresume % resumes program
            [row, col] = find(app.board == 1);
            if col - 1 < 1
                % move not allowed - do nothing
            else
                app.board(row, col - 1) = 1; % update new LED
                app.board(row, col) = 0; % erase old LED
                updateBoard(app)
            end
            uiwait % pauses program
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % randomly arrange ships
            app.shipLoc = shipLocations(app, app.NumberofShipsSpinner.Value);
            
            % create connection with arduino
            app.ard = arduino();
            
            % create board mask
            app.board = zeros(3);
            app.board(1) = 1;
            
            % write LED position to board
            updateBoard(app)
            
            % update numeric edit fields
            app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner.Value;
            app.AttemptsRemainingEditField.Value = 5;
            
            uiwait % pause program until another button is pressed
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.AttemptsRemainingEditField.Value = app.AttemptsRemainingEditField.Value - 1; % decrement attempts remaining
            [row, col] = find(app.board == 1); % get position of guess
            if app.shipLoc(row, col) == 1 % if there is a ship at that location
                app.lightModes(row, col) = 1; % set LED to not light up
                app.ShipsRemainingEditField.Value = app.ShipsRemainingEditField.Value - 1; % decrement ships remaining
                if app.ShipsRemainingEditField.Value == 0 % if we got all the ships
                    % game finish code
                end
            else
                if app.AttemptsRemainingEditField.Value == 0 % if we are out of attempts
                    % game over code
                end
                shipNearby = Distance(app, row, col);
                if shipNearby == true
                    app.lightModes(row, col) = 2;
                else
                    app.lightModes(row, col) = 3;
                end
            end
            
            while app.board(row, col) == 1
                updateBoard(app)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BattleshipGUIUIFigure = uifigure;
            app.BattleshipGUIUIFigure.Name = 'Battleship GUI';
            app.UpButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.RightButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.SelectButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ShipsRemainingEditFieldLabel = uilabel(app.BattleshipGUIUIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.BattleshipGUIUIFigure, 'numeric');
            app.AttemptsRemainingEditFieldLabel = uilabel(app.BattleshipGUIUIFigure);
            app.AttemptsRemainingEditFieldLabel.Text = 'Attempts Remaining';
            app.AttemptsRemainingEditField = uieditfield(app.BattleshipGUIUIFigure, 'numeric');
            app.StartButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.QuitButton.Text = 'Quit';
            app.MissionStatusLabel = uilabel(app.BattleshipGUIUIFigure);
            app.MissionStatusLabel.Text = 'Mission Status';
            app.ControlPanelLabel = uilabel(app.BattleshipGUIUIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.ImportantButtonsLabel = uilabel(app.BattleshipGUIUIFigure);
            app.ImportantButtonsLabel.Text = 'Important Buttons';
            app.SettingsLabel = uilabel(app.BattleshipGUIUIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.NumberofShipsSpinnerLabel = uilabel(app.BattleshipGUIUIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.BattleshipGUIUIFigure);
            app.NumberofShipsSpinner.Limits = [1 9];
            app.NumberofShipsSpinner.Editable = 'off';
            app.NumberofShipsSpinner.Value = 1;
            app.MaximumLengthSpinnerLabel = uilabel(app.BattleshipGUIUIFigure);
            app.MaximumLengthSpinnerLabel.Text = 'Maximum Length';
            app.MaximumLengthSpinner = uispinner(app.BattleshipGUIUIFigure);
            app.MaximumLengthSpinner.Limits = [1 3];
            app.MaximumLengthSpinner.Editable = 'off';
            app.MaximumLengthSpinner.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Copy_of_Battleship_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BattleshipGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BattleshipGUIUIFigure)
        end
    end
end