classdef BattleshipGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        StartGameButton               matlab.ui.control.Button
        QuitGameButton                matlab.ui.control.Button
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        LeftButton                    matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        ShootButton                   matlab.ui.control.Button
        HITLampLabel                  matlab.ui.control.Label
        HITLamp                       matlab.ui.control.Lamp
        MISSLampLabel                 matlab.ui.control.Label
        MISSLamp                      matlab.ui.control.Lamp
        IMPORTANTBUTTONSLabel         matlab.ui.control.Label
        SETTINGSLabel                 matlab.ui.control.Label
        MISSIONSTATUSLabel            matlab.ui.control.Label
        CONTROLPANELLabel             matlab.ui.control.Label
        DownButton                    matlab.ui.control.Button
        BATTLESHIPLabel               matlab.ui.control.Label
        AddisonGregory2019Label       matlab.ui.control.Label
    end


    properties (Access = private)
        Property % Description

        a = arduino;
       pins = ["D12","D11","D10";"D9","D8","D7";"D6","D5","D4"];
       board = [0,0,0;0,0,0;0,0,0];
       ships = [0,0,0;0,0,0;0,0,0];
       memory = [2,2,2;2,2,2;2,2,2];
       dlo = 0;
    end


    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            clc
            if app.NumberofGuessesSpinner.Value < app.NumberofShipsSpinner.Value
                app.dlo=0;
                app.ShotsRemainingEditField.Value=0;
                app.ShipsRemainingEditField.Value=0;
                app.board(2,2)=0;
                UpdateBoard(app.a,app.board);
            elseif app.NumberofGuessesSpinner.Value>=app.NumberofShipsSpinner.Value
            app.dlo=1;
            app.ships(:,:)=0;
            app.board(2,2)=1;
            UpdateBoard(app.a,app.board)
            app.HITLamp.Enable = 0;
            app.MISSLamp.Enable = 0;
            app.ShotsRemainingEditField.Value = app.NumberofGuessesSpinner.Value;
            app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner.Value;
            end
            while sum(sum(app.ships)) < app.NumberofShipsSpinner.Value
                rowa = randi([1 3],1,1);
                cola = randi([1 3],1,1);
                app.ships(rowa,cola)=1;
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.dlo==1
                [r,c] = find(app.board==1);
                if r -1 >= 1
                    app.board(r,c)=0;
                    app.board(r-1,c)=1;
                    UpdateBoard(app.a,app.board);
                    [a1,b1]=find(app.board==1);
                else
                    app.board(r,c)=1;
                    UpdateBoard(app.a,app.board);
                    [a1,b1]=find(app.board==1);
                end
                
                if app.memory(a1,b1)==1
                    app.MISSLamp.Enable=1;
                    app.HITLamp.Enable=0;
                elseif app.memory(a1,b1)==2
                    app.MISSLamp.Enable=0;
                    app.HITLamp.Enable=0;
                elseif app.memory(a1,b1)==3
                    app.HITLamp.Enable=1;
                    app.MISSLamp.Enable=0;
                end
            end
        
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            
            
            if app.dlo==1
                [r,c]=find(app.board==1);
                if c + 1 <=3
                    app.board(r,c)=0;
                    app.board(r,c+1)=1;
                    UpdateBoard(app.a,app.board)
                    [f1,g1]=find(app.board==1);
                else
                    app.board(r,c)=1;
                    UpdateBoard(app.a,app.board);
                    [f1,g1]=find(app.board==1);
                end
                % Defining How i want to do my memory
                if app.memory(f1,g1)==1
                    app.MISSLamp.Enable=1;
                    app.HITLamp.Enable=0;
                elseif app.memory(f1,g1)==2
                    app.MISSLamp.Enable=0;
                    app.HITLamp.Enable=0;
                elseif app.memory(f1,g1)==3
                    app.HITLamp.Enable=1;
                    app.MISSLamp.Enable=0;
                end
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
           
            if app.dlo==1
                [r,c] = find(app.board==1);
                if c - 1>=1
                    app.board(r,c)=0;
                    app.board(r,c-1)=1;
                    UpdateBoard(app.a,app.board);
                    [z1,k1]=find(app.board==1);
                else
                    app.board(r,c)=1;
                    UpdateBoard(app.a,app.board);
                    [z1,k1]=find(app.board==1);
                end
                if app.memory(z1,k1)==1
                    app.MISSLamp.Enable=1;
                    app.HITLamp.Enable=0;
                elseif app.memory(z1,k1)==2
                    app.MISSLamp.Enable=0;
                    app.HITLamp.Enable=0;
                elseif app.memory(z1,k1)==3
                    app.HITLamp.Enable=1;
                    app.MISSLamp.Enable=0;
                end
            end
        
        
        end

        % Button pushed function: QuitGameButton
        function QuitButtonPushed(app, event)
           
        
            if app.dlo==1
                app.NumberofShipsSpinner.Value = 1;
                app.NumberofGuessesSpinner.Value = 1;
                app.ShipsRemainingEditField.Value = 0;
                app.ShotsRemainingEditField.Value= 0;
                app.board(:,:)=0;
                app.ships(:,:)=0;
                app.memory(:,:)=2;
                UpdateBoard(app.a,app.ships);
                UpdateBoard(app.a,app.board);
                app.dlo=0;
            end
        end

        % Button pushed function: ShootButton
        function ShootasGottaShoot(app, event)

            if app.dlo==1
                [zx,zy]=find(app.board==1);
                app.HITLamp.Enable=0;
                app.MISSLamp.Enable=0;
                if app.ships(zx,zy)==1
                    if app.memory(zx,zy)==3
                        app.ShipsRemainingEditField.Value=app.ShipsRemainingEditField.Value-0;
                        app.ShotsRemainingEditField.Value=app.ShotsRemainingEditField.Value-0;
                    else
                        app.memory(zx,zy)=3;
                        app.HITLamp.Enable=1;
                        pause(1)
                        app.HITLamp.Enable=0;
                        app.MISSLamp.Enable=0;
                        app.ShipsRemainingEditField.Value=app.ShipsRemainingEditField.Value-1;
                        app.ShotsRemainingEditField.Value=app.ShotsRemainingEditField.Value-1;
                    end
                end
                if app.ships(zx,zy)==0
                    if app.memory(zx,zy)==2
                        app.ShotsRemainingEditField.Value=app.ShotsRemainingEditField.Value-1;
                        app.memory(zx,zy)=1;
                        app.MISSLamp.Enable=1;
                        pause(1)
                        app.MISSLamp.Enable=0;
                        app.HITLamp.Enable=0;
                    else
                        app.ShotsRemainingEditField.Value=app.ShotsRemainingEditField.Value-0;
                    end
                end
                if app.ShotsRemainingEditField.Value == 0 && app.ShipsRemainingEditField.Value == 0
                    disp('You win, game over.')
                    app.board(:,:)=0;
                    UpdateBoard(app.a,app.board);
                    for rr = 1:3
                        app.board(:,:)=1;
                        UpdateBoard(app.a,app.board);
                        pause(.5);
                        app.board(:,:)=0;
                        UpdateBoard(app.a,app.board);
                    end
                end
                % Light up 3 times, once every half second
                if app.ShipsRemainingEditField.Value==0 && app.ShotsRemainingEditField.Value > 0
                    for rr = 1:3
                        app.board(:,:)=1;
                        UpdateBoard(app.a,app.board);
                        pause(.5);
                        app.board(:,:)=0;
                        UpdateBoard(app.a,app.board);
                    end
                    % Lights up 3 times, once half every second
                    disp('You win, game over.');
                    app.board(:,:)=0;
                    UpdateBoard(app.a,app.board);
                end
                
                if app.ShotsRemainingEditField.Value <= 0 && app.ShipsRemainingEditField.Value>0
                    app.board(:,:)=0;
                    UpdateBoard(app.a,app.board);
                    for rrl = 1:3
                        app.board(:,rrl)=1;
                        UpdateBoard(app.a,app.board);
                        pause(1);
                    end
                    % Each column lights up every half second until full board lit up
                    disp('You lose, game over.');
                    app.board(:,:)=0;
                    UpdateBoard(app.a,app.board);
                end
            end
            
        
        end

        % Button pushed function: DownButton
        function DownButtonPusher(app, event)
            if app.dlo==1
                [r,c] = find(app.board==1);
                if r + 1 <=3
                    app.board(r,c)=0;
                    app.board(r+1,c)=1;
                    UpdateBoard(app.a,app.board);
                    [c1,d1]=find(app.board==1);
                else
                    app.board(r,c)=1;
                    UpdateBoard(app.a,app.board);
                    [c1,d1]=find(app.board==1);
                end
                
                if app.memory(c1,d1)==1
                    app.MISSLamp.Enable=1;
                    app.HITLamp.Enable=0;
                elseif app.memory(c1,d1)==2
                    app.MISSLamp.Enable=0;
                    app.HITLamp.Enable=0;
                elseif app.memory(c1,d1)==3
                    app.HITLamp.Enable=1;
                    app.MISSLamp.Enable=0;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Value = 1;
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.QuitGameButton = uibutton(app.UIFigure, 'push');
            app.QuitGameButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitGameButton.Text = 'Quit Game';
            app.ShotsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShotsRemainingEditFieldLabel.Text = 'Shots Remaining';
            app.ShotsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShipsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.ShootButton = uibutton(app.UIFigure, 'push');
            app.ShootButton.ButtonPushedFcn = createCallbackFcn(app, @ShootasGottaShoot, true);
            app.ShootButton.Text = 'Shoot';
            app.HITLampLabel = uilabel(app.UIFigure);
            app.HITLampLabel.Text = 'HIT';
            app.HITLamp = uilamp(app.UIFigure);
            app.MISSLampLabel = uilabel(app.UIFigure);
            app.MISSLampLabel.Text = 'MISS';
            app.MISSLamp = uilamp(app.UIFigure);
            app.IMPORTANTBUTTONSLabel = uilabel(app.UIFigure);
            app.IMPORTANTBUTTONSLabel.Text = '"IMPORTANT BUTTONS"';
            app.SETTINGSLabel = uilabel(app.UIFigure);
            app.SETTINGSLabel.Text = '"SETTINGS"';
            app.MISSIONSTATUSLabel = uilabel(app.UIFigure);
            app.MISSIONSTATUSLabel.Text = '"MISSION STATUS"';
            app.CONTROLPANELLabel = uilabel(app.UIFigure);
            app.CONTROLPANELLabel.Text = '"CONTROL PANEL"';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPusher, true);
            app.DownButton.Text = 'Down';
            app.BATTLESHIPLabel = uilabel(app.UIFigure);
            app.BATTLESHIPLabel.Text = 'BATTLESHIP';
            app.AddisonGregory2019Label = uilabel(app.UIFigure);
            app.AddisonGregory2019Label.Text = '@AddisonGregory2019';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BattleshipGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end