classdef Section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
        XaxislabelEditFieldLabel  matlab.ui.control.Label
        XaxislabelEditField       matlab.ui.control.EditField
        YaxislabelEditFieldLabel  matlab.ui.control.Label
        YaxislabelEditField       matlab.ui.control.EditField
        DropDownLabel             matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        PhaseEditFieldLabel       matlab.ui.control.Label
        PhaseEditField            matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
        % Section 1 - Introduction to GUIs
        % 1.1 - Create the app
        % 1.2 - Add button, axes and drop down from components tab.
        % 1.3 - Add call back that trigger when your button is pressed.
        % 1.4 - Be able to adjust different features of the graph.
        % 1.5 - Finally, be able to adjust names of axises.
        A = app.AmplitudeEditField.Value;
        B = app.FrequencyEditField.Value;
        C = app.PhaseEditField.Value;
        x = [0:.001:4*pi];
        sinequ = A*sin((B*x)+C);
        cosequ = A*cos((B*x)+C);
        if app.DropDown.Value == 'Sin'
            plot(app.UIAxes,x,sinequ)
            xlim(app.UIAxes, ([0 4*pi]))
            xlabel(app.UIAxes,app.XaxislabelEditField.Value)
            ylabel(app.UIAxes,app.YaxislabelEditField.Value)
            title(app.UIAxes,app.TitleEditField.Value)
        elseif app.DropDown.Value == 'Cos'
            plot(app.UIAxes,x,cosequ);
            xlim(app.UIAxes, ([0 4*pi]))
            xlabel(app.UIAxes,app.XaxislabelEditField.Value)
            ylabel(app.UIAxes,app.YaxislabelEditField.Value)
            title(app.UIAxes,app.TitleEditField.Value)
        end
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.XaxislabelEditFieldLabel = uilabel(app.UIFigure);
            app.XaxislabelEditFieldLabel.Text = 'X-axis label';
            app.XaxislabelEditField = uieditfield(app.UIFigure, 'text');
            app.YaxislabelEditFieldLabel = uilabel(app.UIFigure);
            app.YaxislabelEditFieldLabel.Text = 'Y-axis label';
            app.YaxislabelEditField = uieditfield(app.UIFigure, 'text');
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Sin', 'Cos'};
            app.DropDown.Value = 'Sin';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end