classdef Section4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        StartGameButton               matlab.ui.control.Button
        QuitGameButton                matlab.ui.control.Button
        ShipsRemainigEditFieldLabel   matlab.ui.control.Label
        ShipsRemainigEditField        matlab.ui.control.NumericEditField
        UpButton                      matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        HitLampLabel                  matlab.ui.control.Label
        HitLamp                       matlab.ui.control.Lamp
        MissLampLabel                 matlab.ui.control.Label
        MissLamp                      matlab.ui.control.Lamp
        leftButton                    matlab.ui.control.Button
        rightButton                   matlab.ui.control.Button
        downButton                    matlab.ui.control.Button
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Property % Description
        %The two matrixes are set up to keep track of where the ships are and what like that is.
        Lights=["D12" , "D11" , "D10" ; "D9" , "D8" , "D7" ; "D6" , "D5" , "D4" ];
        Board=zeros(3,3);
        Ard=arduino()
        %R and C keep track of where im at on the board
        R=2
        C=2       
        %A makes sure that no button can be pressed before game start
        A=0


    end


    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
          
     %Checks if ships is higher than guesses then gives an error statment.
           
            if app.NumberofShipsSpinner.Value > app.NumberofGuessesSpinner.Value
              error('Number of Ships can not be larger than Guesses')
            end
            %Lets buttons be pressed
            app.A=1;


            %Resets all values back to 0 and randomizes the ships
            app.ShotsRemainingEditField.Value=app.NumberofGuessesSpinner.Value
            app.ShipsRemainigEditField.Value=app.NumberofShipsSpinner.Value
            app.HitLamp.Enable='off';
            app.MissLamp.Enable='off';
            
            
            app.Board=zeros(3,3);
            K=randperm(9);
            K=K(1:app.NumberofShipsSpinner.Value);
            for n=1:length(K)
                L=K(n);
                app.Board(L)=1;
            end
            [r,c]=size(app.Lights);
            app.R=r-1;
            app.C=c-1;
            %Sets the light in the middle of the board
            writeDigitalPin(app.Ard,app.Lights(app.R,app.C),1)


        end

        % Button pushed function: QuitGameButton
        function QuitGameButtonPushed(app, event)
            %Resets the lights and makes it so no button other than start game will work
            app.A=0;
            for Qw=1:9
                writeDigitalPin(app.Ard,app.Lights(Qw),0)
            end
        end

        % Value changed function: ShipsRemainigEditField
        function ShipsRemainigEditFieldValueChanged(app, event)


        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if app.A==1
                %It goes through the spot on the board where the R and C are at and check them if its a zero or a one, if a one then it is a ship, if zero then it is a miss. It then assigns them a value of 2 or 3 to remeber if it was a hit or miss.
      [xx,yy]=size(app.Board);
                if app.Board(app.R,app.C) == 1
                    app.HitLamp.Enable='on';
                    if app.ShipsRemainigEditField.Value == 1
                        app.ShipsRemainigEditField.Value=0;
                        %this is the winning condition and it's pattern is to make every led blink 3 times going from top to bottom.
                        for x=xx:-1:1
                            for y=yy:-1:1
                                app.Board(x,y)=1;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                pause(1)
                                app.Board(x,y)=0;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                app.Board(x,y)=1;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                pause(1)
                                app.Board(x,y)=0;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                app.Board(x,y)=1;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                pause(1)
                                app.Board(x,y)=0;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                            end
                        end
                        for Qw=1:9
                            writeDigitalPin(app.Ard,app.Lights(Qw),0)
                            
                            app.A=0;
                            disp('Congrats You have Sunk all my Battleships')
                        end
                        
                    end
                    app.ShipsRemainigEditField.Value=app.ShipsRemainigEditField.Value-1;
                    app.ShotsRemainingEditField.Value=app.ShotsRemainingEditField.Value-1;
                    app.Board(app.R,app.C)=3;
                elseif app.Board(app.R,app.C) == 0
                    app.MissLamp.Enable='on';
                    
                    if app.ShotsRemainingEditField.Value == 1 && app.ShipsRemainigEditField.Value >= 1
                        app.ShotsRemainingEditField.Value=0;
                        app.ShipsRemainigEditField.Value=0;
                        %This is the losing condition and its pattern is to blink each led 3 times starting from the bottom and working up
                        for x=1:xx
                            for y=1:yy
                                app.Board(x,y)=1;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                pause(1)
                                app.Board(x,y)=0;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                app.Board(x,y)=1;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                pause(1)
                                app.Board(x,y)=0;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                app.Board(x,y)=1;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                                pause(1)
                                app.Board(x,y)=0;
                                UpdateBoard(app.Ard,app.Board,app.Lights)
                            end
                        end
                        
                        for Qw=1:9
                            writeDigitalPin(app.Ard,app.Lights(Qw),0)
                            disp('Congrats You have not Sunk all my Battleships')
                            app.A=0;
                            
                        end
                    end
                    app.ShotsRemainingEditField.Value=app.ShotsRemainingEditField.Value-1;
                    app.Board(app.R,app.C)=2;
                elseif app.Board(app.R,app.C) == 2
                    
                    
                elseif app.Board(app.R,app.C) == 3
                end
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.A==1
            app.HitLamp.Enable='off';
            app.MissLamp.Enable='off';
            %moves R and C up and check if it is registered as a hit
            if app.R>1
                writeDigitalPin(app.Ard,app.Lights(app.R,app.C),0)
                app.R=app.R-1;
                writeDigitalPin(app.Ard,app.Lights(app.R,app.C),1)
            end
            if app.Board(app.R,app.C) == 2
                app.MissLamp.Enable='on';
                
            elseif app.Board(app.R,app.C) == 3
                app.HitLamp.Enable='on';
            end
            end
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            if app.A==1
            app.HitLamp.Enable='off';
            app.MissLamp.Enable='off';
             %moves R and C lef and check if it is registered as a hit
            if app.C>1
                writeDigitalPin(app.Ard,app.Lights(app.R,app.C),0)
                app.C=app.C-1;
                writeDigitalPin(app.Ard,app.Lights(app.R,app.C),1)
            end
            if app.Board(app.R,app.C) == 2
                app.MissLamp.Enable='on';
                
            elseif app.Board(app.R,app.C) == 3
                app.HitLamp.Enable='on';
            end
            end
        end

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            if app.A==1
             app.HitLamp.Enable='off';
        app.MissLamp.Enable='off';
         %moves R and C down and check if it is registered as a hit
        if app.R<3
            writeDigitalPin(app.Ard,app.Lights(app.R,app.C),0)
            app.R=app.R+1;
            writeDigitalPin(app.Ard,app.Lights(app.R,app.C),1)
        end
        if app.Board(app.R,app.C) == 2
            app.MissLamp.Enable='on';
            
        elseif app.Board(app.R,app.C) == 3
            app.HitLamp.Enable='on';
        end
            end
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            if app.A==1
                app.HitLamp.Enable='off';
            app.MissLamp.Enable='off';
             %moves R and C right and check if it is registered as a hit
            if app.C<3
                writeDigitalPin(app.Ard,app.Lights(app.R,app.C),0)
                app.C=app.C+1;
                writeDigitalPin(app.Ard,app.Lights(app.R,app.C),1)
            end
            if app.Board(app.R,app.C) == 2
                app.MissLamp.Enable='on';
                
            elseif app.Board(app.R,app.C) == 3
                app.HitLamp.Enable='on';
            end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Value = 1;
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.QuitGameButton = uibutton(app.UIFigure, 'push');
            app.QuitGameButton.ButtonPushedFcn = createCallbackFcn(app, @QuitGameButtonPushed, true);
            app.QuitGameButton.Text = 'Quit Game';
            app.ShipsRemainigEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainigEditFieldLabel.Text = 'Ships Remainig';
            app.ShipsRemainigEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShipsRemainigEditField.ValueChangedFcn = createCallbackFcn(app, @ShipsRemainigEditFieldValueChanged, true);
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.HitLampLabel = uilabel(app.UIFigure);
            app.HitLampLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.UIFigure);
            app.HitLamp.Enable = 'off';
            app.MissLampLabel = uilabel(app.UIFigure);
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.UIFigure);
            app.MissLamp.Enable = 'off';
            app.leftButton = uibutton(app.UIFigure, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Text = 'left';
            app.rightButton = uibutton(app.UIFigure, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Text = 'right';
            app.downButton = uibutton(app.UIFigure, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Text = 'down';
            app.ShotsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShotsRemainingEditFieldLabel.Text = 'ShotsRemaining';
            app.ShotsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Section4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end