classdef section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        FunctionDropDownLabel     matlab.ui.control.Label
        FunctionDropDown          matlab.ui.control.DropDown
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
        XAxisLabelEditFieldLabel  matlab.ui.control.Label
        XAxisLabelEditField       matlab.ui.control.EditField
        YAxisLabelEditFieldLabel  matlab.ui.control.Label
        YAxisLabelEditField       matlab.ui.control.EditField
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        PhaseEditFieldLabel       matlab.ui.control.Label
        PhaseEditField            matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            value=app.FunctionDropDown.Value;
            a=app.AmplitudeEditField.Value;
            b=app.FrequencyEditField.Value;
            c=app.PhaseEditField.Value
            
            i=[0:0.1:(4*pi)]
            if value == 'sin'
                yval=a*sin((b*i)+c)
            else
                yval=a*cos((b*i)+c)
            end

            plot(app.UIAxes,i,yval)
            
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            valuetitle = app.TitleEditField.Value;
            title(app.UIAxes,valuetitle)
        end

        % Value changed function: XAxisLabelEditField
        function XAxisLabelEditFieldValueChanged(app, event)
            valuex = app.XAxisLabelEditField.Value;
            xlabel(app.UIAxes,valuex)
        end

        % Value changed function: YAxisLabelEditField
        function YAxisLabelEditFieldValueChanged(app, event)
            valuey = app.YAxisLabelEditField.Value;
            ylabel(app.UIAxes,valuey)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'sin', 'cos'};
            app.FunctionDropDown.Value = 'cos';
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.XAxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.XAxisLabelEditFieldLabel.Text = 'X-Axis Label';
            app.XAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.XAxisLabelEditField.ValueChangedFcn = createCallbackFcn(app, @XAxisLabelEditFieldValueChanged, true);
            app.YAxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisLabelEditFieldLabel.Text = 'Y-Axis Label';
            app.YAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YAxisLabelEditField.ValueChangedFcn = createCallbackFcn(app, @YAxisLabelEditFieldValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end