classdef task2circ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        LightONButton   matlab.ui.control.Button
        LightOFFButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        a=arduino(); % Description
    end
    

    methods (Access = private)

        % Button pushed function: LightONButton
        function LightONButtonPushed(app, event)
            writeDigitalPin(app.a,'D12',1);
            writeDigitalPin(app.a,'D11',1);
            writeDigitalPin(app.a,'D10',1);
            writeDigitalPin(app.a,'D9',1);
            writeDigitalPin(app.a,'D8',1);
            writeDigitalPin(app.a,'D7',1);
            writeDigitalPin(app.a,'D6',1);
            writeDigitalPin(app.a,'D5',1);
            writeDigitalPin(app.a,'D4',1);
        end

        % Button pushed function: LightOFFButton
        function LightOFFButtonPushed(app, event)
            writeDigitalPin(app.a,'D12',0);
            writeDigitalPin(app.a,'D11',0);
            writeDigitalPin(app.a,'D10',0);
            writeDigitalPin(app.a,'D9',0);
            writeDigitalPin(app.a,'D8',0);
            writeDigitalPin(app.a,'D7',0);
            writeDigitalPin(app.a,'D6',0);
            writeDigitalPin(app.a,'D5',0);
            writeDigitalPin(app.a,'D4',0);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LightONButton = uibutton(app.UIFigure, 'push');
            app.LightONButton.ButtonPushedFcn = createCallbackFcn(app, @LightONButtonPushed, true);
            app.LightONButton.Text = 'Light ON';
            app.LightOFFButton = uibutton(app.UIFigure, 'push');
            app.LightOFFButton.ButtonPushedFcn = createCallbackFcn(app, @LightOFFButtonPushed, true);
            app.LightOFFButton.Text = 'Light OFF';
        end
    end

    methods (Access = public)

        % Construct app
        function app = task2circ

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end