classdef section4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MissionStatusLabel            matlab.ui.control.Label
        ImportantButtonsLabel         matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        SettingsLabel                 matlab.ui.control.Label
        ControlPanelLabel             matlab.ui.control.Label
        UpButton                      matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        HitLampLabel                  matlab.ui.control.Label
        HitLamp                       matlab.ui.control.Lamp
        MissLampLabel                 matlab.ui.control.Label
        MissLamp                      matlab.ui.control.Lamp
        AttemptsRemainingEditFieldLabel  matlab.ui.control.Label
        AttemptsRemainingEditField    matlab.ui.control.NumericEditField
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        ard=arduino();
        k=[0,0,0;0,1,0;0,0,0];


    end
    

    methods (Access = private)

        % Value changed function: NumberofShipsSpinner
        function NumberofShipsSpinnerValueChanged(app, event)
            valueship = app.NumberofShipsSpinner.Value;
            
        end

        % Value changed function: NumberofGuessesSpinner
        function NumberofGuessesSpinnerValueChanged(app, event)
            valueguess = app.NumberofGuessesSpinner.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            valueship = app.NumberofShipsSpinner.Value;
            valueguess = app.NumberofGuessesSpinner.Value;
            if valueguess>valueship
                writeDigitalPin(app.ard,'D8',1)
                valueguess = app.NumberofGuessesSpinner.Value;
                app.AttemptsRemainingEditField.Value=valueguess;
                valueship = app.NumberofShipsSpinner.Value;
                app.ShipsRemainingEditField.Value=valueship;
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            writeDigitalPin(app.ard,'D12',0)
            writeDigitalPin(app.ard,'D11',0)
            writeDigitalPin(app.ard,'D10',0)
            writeDigitalPin(app.ard,'D9',0)
            writeDigitalPin(app.ard,'D8',0)
            writeDigitalPin(app.ard,'D7',0)
            writeDigitalPin(app.ard,'D6',0)
            writeDigitalPin(app.ard,'D5',0)
            writeDigitalPin(app.ard,'D4',0)
        end

        % Callback function
        function AttemptsRemainingEditFieldValueChanged(app, event)
           
           
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
           shotsrem=app.AttemptsRemainingEditField.Value;
           shotsrem=shotsrem-1;
           app.AttemptsRemainingEditField.Value=shotsrem;
           if app.AttemptsRemainingEditField.Value==0
               writeDigitalPin(app.ard,'D12',0)
                writeDigitalPin(app.ard,'D11',0)
                writeDigitalPin(app.ard,'D10',0)
                writeDigitalPin(app.ard,'D9',0)
                writeDigitalPin(app.ard,'D8',0)
                writeDigitalPin(app.ard,'D7',0)
                writeDigitalPin(app.ard,'D6',0)
                writeDigitalPin(app.ard,'D5',0)
                writeDigitalPin(app.ard,'D4',0)
           end
           
           if (app.AttemptsRemainingEditField.Value==0) & (app.ShipsRemainingEditField.Value~=0)
              writeDigitalPin(app.ard,'D8',0)
               for i=1:1:3
                writeDigitalPin(app.ard,'D12',1)
                writeDigitalPin(app.ard,'D11',1)
                writeDigitalPin(app.ard,'D10',1)
                pause(0.5)
                writeDigitalPin(app.ard,'D12',0)
                writeDigitalPin(app.ard,'D11',0)
                writeDigitalPin(app.ard,'D10',0)
                pause(0.5)
               end
           end


        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            pins=["D12","D11","D10";"D9","D8","D7";"D6","D5","D4"];
            
             
                k=[0,0,0;0,1,0;0,0,0];
                result=find(k==1);
                writeDigitalPin(app.ard,pins(result),0)
                newpin=pins(result-1)
                writeDigitalPin(app.ard,'D12',0)
                writeDigitalPin(app.ard,'D11',0)
                writeDigitalPin(app.ard,'D10',0)
                writeDigitalPin(app.ard,'D9',0)
                writeDigitalPin(app.ard,'D8',0)
                writeDigitalPin(app.ard,'D7',0)
                writeDigitalPin(app.ard,'D6',0)
                writeDigitalPin(app.ard,'D5',0)
                writeDigitalPin(app.ard,'D4',0)
                writeDigitalPin(app.ard,newpin,1)
                k(result)=0;
                k(result-1)=1;


        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            pins=["D12","D11","D10";"D9","D8","D7";"D6","D5","D4"];
            
                k=[0,0,0;0,1,0;0,0,0];
                result=find(k==1);
                writeDigitalPin(app.ard,pins(result),0)
                newpin=pins(result+1)
                writeDigitalPin(app.ard,'D12',0)
                writeDigitalPin(app.ard,'D11',0)
                writeDigitalPin(app.ard,'D10',0)
                writeDigitalPin(app.ard,'D9',0)
                writeDigitalPin(app.ard,'D8',0)
                writeDigitalPin(app.ard,'D7',0)
                writeDigitalPin(app.ard,'D6',0)
                writeDigitalPin(app.ard,'D5',0)
                writeDigitalPin(app.ard,'D4',0)
                writeDigitalPin(app.ard,newpin,1)
                k(result)=0;
                k(result+1)=1;
             
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            pins=["D12","D11","D10";"D9","D8","D7";"D6","D5","D4"];
            
                k=[0,0,0;0,1,0;0,0,0];
                result=find(k==1);
                writeDigitalPin(app.ard,pins(result),0)
                newpin=pins(result+3)
                writeDigitalPin(app.ard,'D12',0)
                writeDigitalPin(app.ard,'D11',0)
                writeDigitalPin(app.ard,'D10',0)
                writeDigitalPin(app.ard,'D9',0)
                writeDigitalPin(app.ard,'D8',0)
                writeDigitalPin(app.ard,'D7',0)
                writeDigitalPin(app.ard,'D6',0)
                writeDigitalPin(app.ard,'D5',0)
                writeDigitalPin(app.ard,'D4',0)
                writeDigitalPin(app.ard,newpin,1)
                k(result)=0;
                k(result+3)=1;
             
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            pins=["D12","D11","D10";"D9","D8","D7";"D6","D5","D4"];
            
                k=[0,0,0;0,1,0;0,0,0];
                result=find(k==1);
                writeDigitalPin(app.ard,pins(result),0)
                newpin=pins(result-3)
                writeDigitalPin(app.ard,'D12',0)
                writeDigitalPin(app.ard,'D11',0)
                writeDigitalPin(app.ard,'D10',0)
                writeDigitalPin(app.ard,'D9',0)
                writeDigitalPin(app.ard,'D8',0)
                writeDigitalPin(app.ard,'D7',0)
                writeDigitalPin(app.ard,'D6',0)
                writeDigitalPin(app.ard,'D5',0)
                writeDigitalPin(app.ard,'D4',0)
                writeDigitalPin(app.ard,newpin,1)
                k(result)=0;
                k(result-3)=1;
             
        end

        % Value changed function: ShipsRemainingEditField
        function ShipsRemainingEditFieldValueChanged(app, event)
           initial=randperm(9,app.NumberofShipsSpinner.Value);
           new=zeros(3);
           new(initial)=1;
           shiprem = app.ShipsRemainingEditField.Value;
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MissionStatusLabel = uilabel(app.UIFigure);
            app.MissionStatusLabel.Text = 'Mission Status';
            app.ImportantButtonsLabel = uilabel(app.UIFigure);
            app.ImportantButtonsLabel.Text = 'Important Buttons';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofShipsSpinnerValueChanged, true);
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofGuessesSpinnerValueChanged, true);
            app.NumberofGuessesSpinner.Value = 1;
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.HitLampLabel = uilabel(app.UIFigure);
            app.HitLampLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.UIFigure);
            app.MissLampLabel = uilabel(app.UIFigure);
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.UIFigure);
            app.AttemptsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.AttemptsRemainingEditFieldLabel.Text = 'Attempts Remaining';
            app.AttemptsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShipsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShipsRemainingEditField.ValueChangedFcn = createCallbackFcn(app, @ShipsRemainingEditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = section4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end