classdef Battleship < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        SettingsLabel                 matlab.ui.control.Label
        ImportantButtonsLabel         matlab.ui.control.Label
        MissionStatusLabel            matlab.ui.control.Label
        ControlPanelLabel             matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
        UpButton                      matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        HitLampLabel                  matlab.ui.control.Label
        HitLamp                       matlab.ui.control.Lamp
        MissLampLabel                 matlab.ui.control.Label
        MissLamp                      matlab.ui.control.Lamp
        MessageTextAreaLabel          matlab.ui.control.Label
        MessageTextArea               matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        a = arduino();
        pins = ["D12","D11","D10";"D9","D8","D7";"D6","D5","D4"];   % Pins
        board = [0,0,0;0,0,0;0,0,0]; % on or off
        currentLedrow = 2;  %The set row for the start LED
        currentLedcol = 2;  %The set col for the start LED
        StartIndicator = 0; %Set at 0 means none of the other buttons will work until start is pressed
        %% For Guess locations
        guesslocations = [0,0,0;0,0,0;0,0,0]; %0 not guessed, 1 is a sucessful hit, 2 is an unsucessful hit
        shiplocations = [0,0,0;0,0,0;0,0,0]; %There are no ships initially, 0 = no ship, 1 = ship
        c2 = 0; %Counter for defeat/victory blinking
        defeat = [1,0,1;0,1,0;1,0,1]; % X pattern
        victory = [1,0,1;1,0,1;0,1,0]; %Smiley face pattern
        allOff = [0,0,0;0,0,0;0,0,0,]; %for blinking loop
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.NumberofShipsSpinner.Value <= app.NumberofGuessesSpinner.Value %Game will only start is the number of ships is <= number of guesses
                app.HitLamp.Enable = 'off'; %Turns the hit lamp off
                app.MissLamp.Enable = 'off'; %Turns the miss lamp off
                %% For ship information
                randomvec = randperm(9); %Creates random array of integers 1-9
                row1 = randomvec(1,1:3); %top row
                row2 = randomvec(1,4:6); %middle row
                row3 = randomvec(1,7:9); %bottom row
                shipsmatrix = [row1;row2;row3]; %Random Matrix of possible ship locations
                %shiplocations = [0,0,0;0,0,0;0,0,0]; %There are no ships initially, 0 = no ship, 1 = ship
                startingships = 1;                   %minimum number of ships possible
                for i = startingships:app.NumberofShipsSpinner.Value  %increments from the min number of ships to the user chosen value of ships, One ship is added per time the code is run
                    for r = 1:3
                        for c = 1:3
                            if shipsmatrix(r,c) == i                  %Adds ships into the locations of 1,2,3....NumberofShipsSpinner.Value
                                app.shiplocations(r,c) = 1;               %Puts a 1 in the ship locations matrix to indicate a ship at a specific position
                            end
                        end
                    end
                end
                                              
                
                app.StartIndicator = 1;   %Allows other buttons to be pressed
                writeDigitalPin(app.a,app.pins(app.currentLedrow,app.currentLedcol),1); %turns on the middle pin
                app.ShotsRemainingEditField.Value = app.NumberofGuessesSpinner.Value; %sets the guesses/shots remaining in mission status to the same value as the spinner
                app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner.Value; %sets the ships remaining in mission status to the same value as the spinner
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            if app.StartIndicator == 1   %Only works after start is pressed
                                            
                app.board(:,:) = 0;     %Sets all the values in board to zero
                app.shiplocations(:,:) = 0; %deletes ship locations
                app.guesslocations(:,:) = 0; %erases all guess locations
                UpdateBoard(app.a,app.board,app.pins);    %Turns off all the LEDs
                app.ShipsRemainingEditField.Value = 0;    %Resets the informational fields
                app.ShotsRemainingEditField.Value = 0;    %Resets the informational fields
                app.StartIndicator = 0; %Sets the indicator back to zero so no other buttons will work after quit
                app.HitLamp.Enable = 'off'; %Turns the hit lamp off
                app.MissLamp.Enable = 'off'; %Turns the miss lamp off
                app.currentLedrow = 2;  %The set row for the start LED
                app.currentLedcol = 2;  %The set col for the start LED
                app.MessageTextArea.Value = ' '; %Clears the message box
                
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
           if app.StartIndicator == 1   %Only works after start is pressed
            
               if app.currentLedrow > 1 && app.currentLedrow <= 3   %Only works when the current lit LED is in row 2 or 3
               
                   writeDigitalPin(app.a,app.pins(app.currentLedrow-1,app.currentLedcol),1) %Turns on the LED above
                   app.board(app.currentLedrow-1,app.currentLedcol) = 1;                    %Updates the board matrix with 1 in the new position (on)
                   writeDigitalPin(app.a,app.pins(app.currentLedrow,app.currentLedcol),0)   %Turns off the current LED
                   app.board(app.currentLedrow,app.currentLedcol) = 0;                      %Updates the board matrix with 0 in the old position (off)
                   app.currentLedrow = app.currentLedrow - 1;                                       %Repositions the current row value
                   
                                      
                   %Allows the lamps to interact whn moving around the board
                   if app.guesslocations(app.currentLedrow,app.currentLedcol) == 1       %if this spot was a sucessful guess the hit lamp turns on 
                       app.HitLamp.Enable = 'on';
                       app.MissLamp.Enable = 'off';
                   elseif app.guesslocations(app.currentLedrow,app.currentLedcol) == 2   %if this spot was an unsucessful guess the miss lamp turns on
                       app.MissLamp.Enable = 'on';
                       app.HitLamp.Enable = 'off';
                   elseif app.guesslocations(app.currentLedrow,app.currentLedcol) == 0   %if this spot hasn't been guessed both lamps turn off
                       app.HitLamp.Enable = 'off';
                       app.MissLamp.Enable = 'off';
                   end 
               end
           end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
             if app.StartIndicator == 1   %Only works after start is pressed
            
               if app.currentLedrow >= 1 && app.currentLedrow < 3  %Only works when the current lit LED is in row 1 or 2
                   
                   writeDigitalPin(app.a,app.pins(app.currentLedrow +1,app.currentLedcol),1) %Turns on the below above
                   app.board(app.currentLedrow +1,app.currentLedcol) = 1;                    %Updates the board matrix with 1 in the new position (on)
                   writeDigitalPin(app.a,app.pins(app.currentLedrow,app.currentLedcol),0)   %Turns off the current LED
                   app.board(app.currentLedrow,app.currentLedcol) = 0;                      %Updates the board matrix with 0 in the old position (off)
                   app.currentLedrow = app.currentLedrow + 1;                               %Repositions the current row value
                   
                    %Allows the lamps to interact whn moving around the board
                   if app.guesslocations(app.currentLedrow,app.currentLedcol) == 1       %if this spot was a sucessful guess the hit lamp turns on 
                       app.HitLamp.Enable = 'on';
                       app.MissLamp.Enable = 'off';
                   elseif app.guesslocations(app.currentLedrow,app.currentLedcol) == 2   %if this spot was an unsucessful guess the miss lamp turns on
                       app.MissLamp.Enable = 'on';
                       app.HitLamp.Enable = 'off';
                   elseif app.guesslocations(app.currentLedrow,app.currentLedcol) == 0   %if this spot hasn't been guessed both lamps turn off
                       app.HitLamp.Enable = 'off';
                       app.MissLamp.Enable = 'off';
                   end
               end
             end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if app.StartIndicator == 1   %Only works after start is pressed
            
               if app.currentLedcol > 1 && app.currentLedcol <= 3   %Only works when the current lit LED is in col 2 or 3
               
                   writeDigitalPin(app.a,app.pins(app.currentLedrow,app.currentLedcol-1),1) %Turns on the LED left
                   app.board(app.currentLedrow,app.currentLedcol-1) = 1;                    %Updates the board matrix with 1 in the new position (on)
                   writeDigitalPin(app.a,app.pins(app.currentLedrow,app.currentLedcol),0)   %Turns off the current LED
                   app.board(app.currentLedrow,app.currentLedcol) = 0;                      %Updates the board matrix with 0 in the old position (off)
                   app.currentLedcol = app.currentLedcol - 1;                               %Repositions the current col value
                   
                    %Allows the lamps to interact whn moving around the board
                   if app.guesslocations(app.currentLedrow,app.currentLedcol) == 1       %if this spot was a sucessful guess the hit lamp turns on 
                       app.HitLamp.Enable = 'on';
                       app.MissLamp.Enable = 'off';
                   elseif app.guesslocations(app.currentLedrow,app.currentLedcol) == 2   %if this spot was an unsucessful guess the miss lamp turns on
                       app.MissLamp.Enable = 'on';
                       app.HitLamp.Enable = 'off';
                   elseif app.guesslocations(app.currentLedrow,app.currentLedcol) == 0   %if this spot hasn't been guessed both lamps turn off
                       app.HitLamp.Enable = 'off';
                       app.MissLamp.Enable = 'off';
                   end
                                   
                  
               end
           end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if app.StartIndicator == 1   %Only works after start is pressed
            
               if app.currentLedcol >= 1 && app.currentLedcol < 3   %Only works when the current lit LED is in col 1 or 2
               
                   writeDigitalPin(app.a,app.pins(app.currentLedrow,app.currentLedcol+1),1) %Turns on the LED right
                   app.board(app.currentLedrow,app.currentLedcol+1) = 1;                    %Updates the board matrix with 1 in the new position (on)
                   writeDigitalPin(app.a,app.pins(app.currentLedrow,app.currentLedcol),0)   %Turns off the current LED
                   app.board(app.currentLedrow,app.currentLedcol) = 0;                      %Updates the board matrix with 0 in the old position (off)
                   app.currentLedcol = app.currentLedcol + 1;                               %Repositions the current col value
                   
                    %Allows the lamps to interact whn moving around the board
                   if app.guesslocations(app.currentLedrow,app.currentLedcol) == 1       %if this spot was a sucessful guess the hit lamp turns on 
                       app.HitLamp.Enable = 'on';
                       app.MissLamp.Enable = 'off';
                   elseif app.guesslocations(app.currentLedrow,app.currentLedcol) == 2   %if this spot was an unsucessful guess the miss lamp turns on
                       app.MissLamp.Enable = 'on';
                       app.HitLamp.Enable = 'off';
                   elseif app.guesslocations(app.currentLedrow,app.currentLedcol) == 0   %if this spot hasn't been guessed both lamps turn off
                       app.HitLamp.Enable = 'off';
                       app.MissLamp.Enable = 'off';
                   end


               end
           end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if app.StartIndicator == 1 %Only works after start is pressed
                
                %%Keeps track of the guesses locations
             if app.guesslocations(app.currentLedrow,app.currentLedcol) == 0   %Makes sure that you can't guess at the same position, below code will only run if the led hasnt been guessed on already: equal to 0
%                 %%Updates guess matrix to keep track of guess locations
%                    app.guesslocations(app.currentLedrow,app.currentLedcol) = 1;
                            
                               
                %%Subtracts a guess everytime select is pressed
                if app.ShotsRemainingEditField.Value >= 1      %Checks that there is at least 1 guess left
                    app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value - 1; %Reduces the value by one when the guess(select) is made
                    
                end
                
                if app.ShipsRemainingEditField.Value >= 1      %Checks that there is at least 1 ship left
                  if app.board(app.currentLedrow,app.currentLedcol) == app.shiplocations(app.currentLedrow,app.currentLedcol) %If a ship is hit meaning the LED location = ship location when select is pressed
                    app.ShipsRemainingEditField.Value = app.ShipsRemainingEditField.Value - 1;  %Subtracts 1 from the number of ships remaining
                    app.HitLamp.Enable = 'on';   %Illuminates the lamp indicating a hit
                    app.guesslocations(app.currentLedrow,app.currentLedcol) = 1; %Updates guess matrix to 1 when there is a sucessful hit
                  elseif app.board(app.currentLedrow,app.currentLedcol) ~= app.shiplocations(app.currentLedrow,app.currentLedcol)
                    app.guesslocations(app.currentLedrow,app.currentLedcol) = 2; %Updates guess matrix to 2 when there is an unsucessful hit
                    app.MissLamp.Enable = 'on'; %Illuminates the lamp indicating a miss
                  end
                end
                if app.ShipsRemainingEditField.Value == 0 && app.ShotsRemainingEditField.Value >=0
                    pause(2);
                    
                    app.MessageTextArea.Value = 'Congratulations, you sunk all the ships!';
                    disp('Congratulations, You sunk all the ships!'); %Displays message in cmd window                    
                    
                    %Special pattern here - for win
                    while app.c2 < 3
                    UpdateBoard(app.a,app.victory,app.pins);    %Flashes an X pattern 3 times to signal victory
                    pause(1);
                    UpdateBoard(app.a,app.allOff,app.pins);
                    pause(1);
                    app.c2 = app.c2 + 1;
                    end
                    
                    app.QuitButtonPushed();                    %Calls the QuitButton function to end the program.
                
                elseif app.ShotsRemainingEditField.Value == 0 && app.ShipsRemainingEditField.Value >=1 %On the last guess this will become true and the program will terminate if ships still left, but no guesses left
                    pause(2);
                    
                    app.MessageTextArea.Value = 'The game has ended, you lose!';
                    disp('The game has ended, you lose!');  %Displays message in cmd window                 
                    
                    %Special pattern here - for defeat 
                    while app.c2 < 3
                    UpdateBoard(app.a,app.defeat,app.pins);    %Flashes an X pattern 3 times to signal defeat
                    pause(1);
                    UpdateBoard(app.a,app.allOff,app.pins);
                    pause(1);
                    app.c2 = app.c2 + 1;
                    
                    end
                    
                    app.QuitButtonPushed();                    %Calls the QuitButton function to end the program.
                 end
              end
             
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Value = 1;
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = {'Settings'; ''};
            app.ImportantButtonsLabel = uilabel(app.UIFigure);
            app.ImportantButtonsLabel.Text = 'Important Buttons';
            app.MissionStatusLabel = uilabel(app.UIFigure);
            app.MissionStatusLabel.Text = 'Mission Status';
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ShipsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShotsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShotsRemainingEditFieldLabel.Text = 'Shots Remaining';
            app.ShotsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.HitLampLabel = uilabel(app.UIFigure);
            app.HitLampLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.UIFigure);
            app.MissLampLabel = uilabel(app.UIFigure);
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.UIFigure);
            app.MessageTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Battleship

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end