classdef BattleShip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UpButton                      matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        FireButton                    matlab.ui.control.Button
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofShotsSpinnerLabel     matlab.ui.control.Label
        NumberofShotsSpinner          matlab.ui.control.Spinner
        SettingsLabel                 matlab.ui.control.Label
        ControlPanelLabel             matlab.ui.control.Label
        StartGameButton               matlab.ui.control.Button
        EndGameButton                 matlab.ui.control.Button
        HitLampLabel                  matlab.ui.control.Label
        HitLamp                       matlab.ui.control.Lamp
        MissLampLabel                 matlab.ui.control.Label
        MissLamp                      matlab.ui.control.Lamp
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
        EnemyShipsRemaningEditFieldLabel  matlab.ui.control.Label
        EnemyShipsRemainingEditField  matlab.ui.control.NumericEditField
        MissionStatusLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        %Create arduino  
        a=arduino();
        
        %Create pins matrix
        pins=["D12" "D11" "D10";
            "D9" "D8" "D7";
            "D6" "D5" "D4"];
        %Create board matrix
        board=[0,0,0;
            0,0,0;
            0,0,0];
        
        %Initialize row position of cursor
        x=0;
        
        %Initialize column position of cursor
        y=0;
        
        %Initalize matrix of ships
        GameBoard=[0,0,0;
                   0,0,0;
                   0,0,0]
         
        %Initialize position matrix of shots 
         ShotMatrix=[0,0,0;
                     0,0,0;
                     0,0,0];
        
        %Initialize random vector from 1 to 3 
        ShipVector=randperm(9);
        
        
    end
    

    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            
            %Preventing the game to start if the number of ships is greater than the number of guesses.
            if app.NumberofShipsSpinner.Value >= app.NumberofShotsSpinner.Value
                error('The number of ships must be less than the number of guesses. Please change your game settings.');
            end
            
            %Setting the number of ships and shots remaining to the inputted values
            app.ShotsRemainingEditField.Value = app.NumberofShotsSpinner.Value;
            app.EnemyShipsRemainingEditField.Value= app.NumberofShipsSpinner.Value;
            
            %Lighting up center LED 
            app.x=2;
            app.y=2;
            app.board(app.x,app.y)=1;
            UpdateBoard(app.a,app.board,app.pins);
            
            %One Ship Chosen
            if app.NumberofShipsSpinner.Value ==1
              app.GameBoard(app.ShipVector(1))=1;
               
            end
            
            
            %Two Ships Chosen
            if app.NumberofShipsSpinner.Value==2
                app.GameBoard(app.ShipVector(1:1:2))=1;
                
            end


            %Three Ships Chosen
            if app.NumberofShipsSpinner.Value==3
                app.GameBoard(app.ShipVector(1:1:3))=1;
                
            end


            %Four Ships Chosen
            
            if app.NumberofShipsSpinner.Value==4
                app.GameBoard(app.ShipVector(1:1:4))=1;
            end


        end

        % Value changed function: ShotsRemainingEditField
        function ShotsRemainingEditFieldValueChanged(app, event)
            value=app.ShotsRemainingEditField.Value
        end

        % Button pushed function: FireButton
        function FireButtonPushed(app, event)
            %Decrease number of shots every time fire is pressed
            app.ShotsRemainingEditField.Value= app.ShotsRemainingEditField.Value -1


            %Hit Condition
            if app.board(app.x,app.y) == app.GameBoard(app.x,app.y)
                app.EnemyShipsRemainingEditField.Value= app.EnemyShipsRemainingEditField.Value-1
                app.GameBoard(app.x,app.y)=2;
                app.HitLamp.Enable="on"
                pause(2)
                app.HitLamp.Enable="off"
                
            end


           %Miss Condition 
           if app.board(app.x,app.y) ~= app.GameBoard(app.x,app.y)
               app.MissLamp.Enable="on"
               pause(2)
               app.MissLamp.Enable="off"
           end


            %Defeat Condition 
            if app.ShotsRemainingEditField.Value==0 & app.EnemyShipsRemainingEditField.Value >0
                app.board=[0,0,0;
                           0,0,0;
                           0,0,0];
                fprintf('\n\nYOU LOSE');

                %Blinks in an X pattern three times
                for x=1:1:3
                app.board=[1,0,1;
                           0,1,0;
                           1,0,1];
                 UpdateBoard(app.a,app.board,app.pins);
                 pause(1)
                 app.board=[0,0,0;
                           0,0,0;
                           0,0,0];
                 UpdateBoard(app.a,app.board,app.pins);
                 pause(1)

                 
                end
                
            end
            
            %Victory win condition #1
            if app.EnemyShipsRemainingEditField.Value==0
                 app.board=[0,0,0;
                           0,0,0;
                           0,0,0];
                fprintf('YOU WIN');

                %Blink in diamond pattern three times
                for x=1:1:3
                app.board=[0,1,0;
                           1,0,1;
                           0,1,0;];
                UpdateBoard(app.a,app.board,app.pins);
                pause(1)
                app.board=[0,0,0;
                           0,0,0;
                           0,0,0];
                UpdateBoard(app.a,app.board,app.pins);
                
                end
                
            end
            
            %Victory win Condition #2 
            if app.EnemyShipsRemainingEditField.Value==0 & app.ShotsRemainingEditField.Value==0
                %Blink in Diamond pattern three times 
                fprintf('\n\nYOU WIN');
                for x=1:1:3
                app.board=[0,0,0;
                           0,0,0;
                           0,0,0];
                app.board=[0,1,0;
                           1,0,1;
                           0,1,0;];
                UpdateBoard(app.a,app.board,app.pins);
                pause(1)
                
                end
            end
    
       
        end

        % Button pushed function: EndGameButton
        function EndGameButtonPushed(app, event)
            %Turn off all currently lit LEDs 
            app.board=[0,0,0;
                0,0,0;
                0,0,0];
            UpdateBoard(app.a,app.board,app.pins);
            app.x=0;
            app.y=0;
            
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
           
           %Prevent the cursor from leaving board
           while  app.x==1
               uiwait();
            app.x=app.x+1;
            app.board(app.x,app.y)=1;
            app.board(app.x-1,app.y)=0;
            UpdateBoard(app.a,app.board,app.pins);
            
            
           end
            
            %Updates position of cursor to position above 
            app.x=app.x-1;
            app.board(app.x,app.y)=1;
            app.board(app.x+1,app.y)=0;
            UpdateBoard(app.a,app.board,app.pins);


        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            %Updates position of cursor to position below 
            app.x=app.x+1;
            app.board(app.x,app.y)=1;
            app.board(app.x-1,app.y)=0;
            UpdateBoard(app.a,app.board,app.pins);
            
            %Prevents the cursor from exiting the board from below 
            if app.x>3
                app.x=app.x-1
                app.board(app.x,app.y)=1;
                UpdateBoard(app.a,app.board,app.pins);
                
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            %Updates the cursor to one position to the right
            app.y=app.y+1;
            app.board(app.x,app.y)=1;
            app.board(app.x,app.y-1)=0;
            UpdateBoard(app.a,app.board,app.pins);
            
            %Prevents the cursor from leaving the board to the right 
            if app.y>3
                app.y=app.y-1
                app.board(app.x,app.y)=1;
                UpdateBoard(app.a,app.board,app.pins);
                
            end
            
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            %Prevents the cursor from leaving the board to the left 
            while  app.y==1
            uiwait();
            app.y=app.y+1;
            app.board(app.x,app.y)=1;
            app.board(app.x-1,app.y)=0;
            UpdateBoard(app.a,app.board,app.pins);
            
            end
         
            %Updates the cursor to one position to the left 
            app.y=app.y-1;
            app.board(app.x,app.y)=1;
            app.board(app.x,app.y+1)=0;
            UpdateBoard(app.a,app.board,app.pins);
            
            
        end

        % Value changed function: EnemyShipsRemainingEditField
        function EnemyShipsRemainingEditFieldValueChanged(app, event)
            value = app.EnemyShipsRemainingEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.FireButton = uibutton(app.UIFigure, 'push');
            app.FireButton.ButtonPushedFcn = createCallbackFcn(app, @FireButtonPushed, true);
            app.FireButton.Text = 'Fire!';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofShotsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShotsSpinnerLabel.Text = 'Number of Shots';
            app.NumberofShotsSpinner = uispinner(app.UIFigure);
            app.NumberofShotsSpinner.Limits = [1 9];
            app.NumberofShotsSpinner.Value = 1;
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.EndGameButton = uibutton(app.UIFigure, 'push');
            app.EndGameButton.ButtonPushedFcn = createCallbackFcn(app, @EndGameButtonPushed, true);
            app.EndGameButton.Text = 'End Game';
            app.HitLampLabel = uilabel(app.UIFigure);
            app.HitLampLabel.Enable = 'off';
            app.HitLampLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.UIFigure);
            app.HitLamp.Enable = 'off';
            app.MissLampLabel = uilabel(app.UIFigure);
            app.MissLampLabel.Enable = 'off';
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.UIFigure);
            app.MissLamp.Enable = 'off';
            app.ShotsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShotsRemainingEditFieldLabel.Text = 'Shots Remaining';
            app.ShotsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShotsRemainingEditField.ValueChangedFcn = createCallbackFcn(app, @ShotsRemainingEditFieldValueChanged, true);
            app.EnemyShipsRemaningEditFieldLabel = uilabel(app.UIFigure);
            app.EnemyShipsRemaningEditFieldLabel.Text = 'Enemy Ships Remaning';
            app.EnemyShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnemyShipsRemainingEditField.ValueChangedFcn = createCallbackFcn(app, @EnemyShipsRemainingEditFieldValueChanged, true);
            app.MissionStatusLabel = uilabel(app.UIFigure);
            app.MissionStatusLabel.Text = 'Mission Status';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BattleShip

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end