classdef section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        PlotButton               matlab.ui.control.Button
        FunctionDropDownLabel    matlab.ui.control.Label
        FunctionDropDown         matlab.ui.control.DropDown
        FrequencyEditFieldLabel  matlab.ui.control.Label
        FrequencyEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        PhaseEditFieldLabel      matlab.ui.control.Label
        PhaseEditField           matlab.ui.control.NumericEditField
        TitleEditFieldLabel      matlab.ui.control.Label
        TitleEditField           matlab.ui.control.EditField
        XAxisEditFieldLabel      matlab.ui.control.Label
        XAxisEditField           matlab.ui.control.EditField
        YAxisEditFieldLabel      matlab.ui.control.Label
        YAxisEditField           matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            syms x
            
            B= app.FrequencyEditField.Value;
            A= app.AmplitudeEditField.Value;
            C= app.PhaseEditField.Value;
            
            app.FunctionDropDown.ItemsData=[1,2]
            
            if app.FunctionDropDown.Value ==1
                
                fplot(app.UIAxes,A*sin(B*x +C));
                xlim(app.UIAxes,[0,4*pi]);
                
            elseif app.FunctionDropDown.Value==2
                   
                fplot(app.UIAxes,A*cos(B*x +C));
                xlim(app.UIAxes,[0,4*pi]);
                
            end
                
            xlabel(app.UIAxes,app.XAxisEditField.Value);
            ylabel(app.UIAxes,app.YAxisEditField.Value);
            title(app.UIAxes,app.TitleEditField.Value);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sine/Cosine Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'Sine', 'Cosine'};
            app.FunctionDropDown.ItemsData = {'1', '2'};
            app.FunctionDropDown.Value = '1';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.XAxisEditFieldLabel = uilabel(app.UIFigure);
            app.XAxisEditFieldLabel.Text = 'X-Axis';
            app.XAxisEditField = uieditfield(app.UIFigure, 'text');
            app.YAxisEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisEditFieldLabel.Text = 'Y-Axis';
            app.YAxisEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end