classdef Battleship < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BattleshipUIFigure            matlab.ui.Figure
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        HitLampLabel                  matlab.ui.control.Label
        HitLamp                       matlab.ui.control.Lamp
        MissLampLabel                 matlab.ui.control.Label
        MissLamp                      matlab.ui.control.Lamp
        StartButton                   matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
        NumberofShotsSpinnerLabel     matlab.ui.control.Label
        NumberofShotsSpinner          matlab.ui.control.Spinner
        ControlsLabel                 matlab.ui.control.Label
        FireButton                    matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        SettingsLabel                 matlab.ui.control.Label
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        pins = ["D12","D11","D10";"D9","D8","D7";"D6","D5","D4"]; %This is the layout of the pins in the arduino.
        ard = arduino % Initializes the arduino
        randomizer = []; %Initializes randomizer matrix.
        ships = []; %Initializes the ships matrix. A '1' will signify the location of a ship.
        board = []; %Initializes the board matrix which will be used for the locator matrix.
        
        %Programming the controls
        locator = [0,0,0;0,1,0;0,0,0];  % a "1" indicates where the cursor is currently.
        
        %Programming the guesses
        shots = zeros(3); 
                    % A '0' will indicate that this location hasn't been guessed yet.
                    % A '1' will indicate that this location has been guessed, and it missed. 
                    % A '2' will indicate that this location has been guessed, and it hit. 
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner.Value;
            app.ShotsRemainingEditField.Value = app.NumberofShotsSpinner.Value;
            if app.ShipsRemainingEditField.Value > app.ShotsRemainingEditField.Value
                error('Number of ships set is less than the number of shots left.')
            end
            writeDigitalPin(app.ard,app.pins(2,2),1)
            
            %Create the matrices that will locate the hidden ships, and remember the shots.
            app.randomizer = rand(3); % The minimums in this matrix will determine the location of the ships.
            app.shots = zeros(3);
            app.ships = zeros(3); %This is where the locations of the ships will be.
            
            numships = app.NumberofShipsSpinner.Value;
            for n = 1:1:numships
                [~,c] = min(min(app.randomizer));
                [~,r]= min(app.randomizer(:,c));
                app.ships(r,c) = 1; % A '1' indicates the position of a ship. 
                app.randomizer(r,c) = 100;
            end
            
            %Turn on the controls.
            set(app.UpButton, 'Enable', 'on')
            set(app.DownButton, 'Enable', 'on')
            set(app.LeftButton, 'Enable', 'on')
            set(app.RightButton, 'Enable', 'on')
            set(app.FireButton, 'Enable', 'on')
            
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            %Turn off the LEDs.
            app.board = zeros(3);
            UpdateBoard(app.board,app.pins,app.ard)
            
            %Reset the matrices, as they were when the GUI was opened.
            app.ships = [];
            app.locator = [0,0,0;0,1,0;0,0,0];
            app.shots = [];
            
            %Set the edit fields back to 0.
            app.ShipsRemainingEditField.Value = 0;
            app.ShotsRemainingEditField.Value = 0;
            
            %Set the controls off.
            set(app.UpButton, 'Enable', 'off')
            set(app.DownButton, 'Enable', 'off')
            set(app.LeftButton, 'Enable', 'off')
            set(app.RightButton, 'Enable', 'off')
            set(app.FireButton, 'Enable', 'off')    
            
            %Set the lamps off.
            set(app.MissLamp, 'Enable', 'off')
            set(app.HitLamp, 'Enable', 'off')    
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            %Find the current location of the LED
            [~,c] = max(max(app.locator));
            [~,r] = max(app.locator(:,c));
            % the location [r,c] corresponds to the current location of the LED.
            
            if r > 1 % r cant be 1. This part moves the cursor. The new location is [up,c].
                %Turn off all the lamps
                set(app.MissLamp, 'Enable', 'off')
                set(app.HitLamp, 'Enable', 'off')
                %Find the current location of locator.
                app.locator = zeros(3);
                up = r-1;
                app.locator(up,c) = 1;
                app.board = app.locator;
                UpdateBoard(app.board,app.pins,app.ard)
                %Program the behavior of the lamps.
                if app.shots(up,c) == 2
                    set(app.MissLamp, 'Enable', 'off')
                    set(app.HitLamp, 'Enable', 'on')
                elseif app.shots(up,c) == 1
                    set(app.MissLamp, 'Enable', 'on')
                    set(app.HitLamp, 'Enable', 'off')
                elseif app.shots(up,c) == 0
                    set(app.MissLamp, 'Enable', 'off')
                    set(app.HitLamp, 'Enable', 'off')
                end
            end
            
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            % the location [r,c] corresponds to the current location of the LED.
            %Find the current location of the LED
                [~,c] = max(max(app.locator));
                [~,r] = max(app.locator(:,c));
                
            if r < 3 %r cant be 3. This part moves the cursor into [down,c].
                %Turn off all the lamps
                set(app.MissLamp, 'Enable', 'off')
                set(app.HitLamp, 'Enable', 'off')
                app.locator = zeros(3);
                down = r+1;
                app.locator(down,c) = 1;
                app.board = app.locator;
                UpdateBoard(app.board,app.pins,app.ard);
                %Program the behavior of the lamps.
                if app.shots(down,c) == 2
                    set(app.MissLamp, 'Enable', 'off')
                    set(app.HitLamp, 'Enable', 'on')
                elseif app.shots(down,c) == 1
                    set(app.MissLamp, 'Enable', 'on')
                    set(app.HitLamp, 'Enable', 'off')
                elseif app.shots(down,c) == 0
                    set(app.MissLamp, 'Enable', 'off')
                    set(app.HitLamp, 'Enable', 'off')
                end
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            %Find the current location of the LED
            [~,c] = max(max(app.locator));
            [~,r] = max(app.locator(:,c));
            % the location [r,c] corresponds to the current location of the LED.
            
            if c > 1 %C cannot be 1. The new location [r,left] corresponds to the location of the cursor.
                %Turn off all the lamps
                set(app.MissLamp, 'Enable', 'off')
                set(app.HitLamp, 'Enable', 'off')
                %Find the current location of locator.
                app.locator = zeros(3);
                left = c-1;
                app.locator(r,left) = 1;
                app.board = app.locator;
                UpdateBoard(app.board,app.pins,app.ard);
                %Program the behavior of the lamps.
                if app.shots(r,left) == 2
                    set(app.MissLamp, 'Enable', 'off')
                    set(app.HitLamp, 'Enable', 'on')
                elseif app.shots(r,left) == 1
                    set(app.MissLamp, 'Enable', 'on')
                    set(app.HitLamp, 'Enable', 'off')
                elseif app.shots(r,left) == 0
                    set(app.MissLamp, 'Enable', 'off')
                    set(app.HitLamp, 'Enable', 'off')
                end
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            %Find the current location of the LED
            [~,c] = max(max(app.locator));
            [~,r] = max(app.locator(:,c));
            % the location [r,c] corresponds to the current location of the LED.
            
            if c < 3 %C cannot be 3. The location [r,right] corresponds to the new location of the cursor.
                %Turn off all the lamps
                set(app.MissLamp, 'Enable', 'off')
                set(app.HitLamp, 'Enable', 'off')
                %Find the current location of locator.
                app.locator = zeros(3);
                right = c+1;
                app.locator(r,right) = 1;
                app.board = app.locator;
                UpdateBoard(app.board,app.pins,app.ard);
                %Program the behavior of the lamps.
                if app.shots(r,right) == 2
                    set(app.MissLamp, 'Enable', 'off')
                    set(app.HitLamp, 'Enable', 'on')
                elseif app.shots(r,right) == 1
                    set(app.MissLamp, 'Enable', 'on')
                    set(app.HitLamp, 'Enable', 'off')
                elseif app.shots(r,right) == 0
                    set(app.MissLamp, 'Enable', 'off')
                    set(app.HitLamp, 'Enable', 'off')
                end
            end
            
        end

        % Button pushed function: FireButton
        function FireButtonPushed(app, event)
            %We need to find the location of ships to see if we hit a ship.
                
            r_ships = [];
            c_ships = [];
            
                %Find the location (row and column) and store them in a vector.
                for x = 1:3
                    for y = 1:3
                        if app.ships(x,y) == 1
                        r_ships = [r_ships,x];
                        c_ships = [c_ships,y];
                        end
                    end
                end
                %Find the current location of locator.
                [~,c_loc] = max(max(app.locator));
                [~,r_loc] = max(app.locator(:,c_loc));
                %Take the shot only if the location hasn't been guessed before
                if app.shots(r_loc,c_loc) == 0
                    if app.ships(r_loc,c_loc ) > 0
                        app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value -1;
                        app.ShipsRemainingEditField.Value = app.ShipsRemainingEditField.Value - 1;
                        set(app.MissLamp, 'Enable', 'off')
                        set(app.HitLamp, 'Enable', 'on')
                        app.shots(r_loc,c_loc) = 2;
                        if app.ShotsRemainingEditField.Value >= 0 && app.ShipsRemainingEditField.Value == 0
                            disp('Congratulations! You have won!')
                            set(app.UpButton, 'Enable', 'off') %Lock the controls.
                            set(app.DownButton, 'Enable', 'off')
                            set(app.LeftButton, 'Enable', 'off')
                            set(app.RightButton, 'Enable', 'off')
                            set(app.FireButton, 'Enable', 'off')
                            wlseq = zeros(3);
                            for n = 1:3
                                wlseq(3,:) = 1;
                                UpdateBoard(wlseq,app.pins,app.ard)
                                pause(0.3)
                                wlseq(2,:) = 1;
                                UpdateBoard(wlseq,app.pins,app.ard)
                                pause(0.3)
                                wlseq(1,:) = 1;
                                UpdateBoard(wlseq,app.pins,app.ard)
                                pause(0.75)
                                wlseq = zeros(3);
                                UpdateBoard(wlseq,app.pins,app.ard)
                                pause(0.3)
                            end
                        end
                            % This command window will display "Congratulations, you have won!"
                            % The lights will start from the bottom row and increase by row until all rows are lit.
                            % Then all LEDs will turn off. This process will repeat 3 times.
                    elseif app.ships(r_loc,c_loc) == 0
                        app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value -1;
                        app.shots(r_loc,c_loc) = 1;
                        set(app.MissLamp, 'Enable', 'on')
                        set(app.HitLamp, 'Enable', 'off')
                        if app.ShipsRemainingEditField.Value > 0 && app.ShotsRemainingEditField.Value == 0
                            disp('You have lost.')
                            wlseq = zeros(3);
                            set(app.UpButton, 'Enable', 'off') %Lock the controls.
                            set(app.DownButton, 'Enable', 'off')
                            set(app.LeftButton, 'Enable', 'off')
                            set(app.RightButton, 'Enable', 'off')
                            set(app.FireButton, 'Enable', 'off')
                            for n = 1:3
                                wlseq(1,:) = 1;
                                UpdateBoard(wlseq,app.pins,app.ard)
                                pause(0.3)
                                wlseq(2,:) = 1;
                                UpdateBoard(wlseq,app.pins,app.ard)
                                pause(0.3)
                                wlseq(3,:) = 1;
                                UpdateBoard(wlseq,app.pins,app.ard)
                                pause(0.75)
                                wlseq = zeros(3);
                                UpdateBoard(wlseq,app.pins,app.ard)
                                pause(0.4)
                                %I have created this user-defined function for a loss sequence.
                                %The command window will display "You have lost."
                                %The lights will move downwards and have all LEDs light up 3 times
                            end
                        end
                    end
                end
    
        end

        % Callback function
        function ButtonPushed(app, event)
            disp('locator')
            disp(app.locator)
            disp('shots')
            disp(app.shots)
            disp('ships')
            disp(app.ships)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BattleshipUIFigure = uifigure;
            app.BattleshipUIFigure.Name = 'Battleship';
            app.NumberofShipsSpinnerLabel = uilabel(app.BattleshipUIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.BattleshipUIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.HitLampLabel = uilabel(app.BattleshipUIFigure);
            app.HitLampLabel.Enable = 'off';
            app.HitLampLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.BattleshipUIFigure);
            app.HitLamp.Enable = 'off';
            app.MissLampLabel = uilabel(app.BattleshipUIFigure);
            app.MissLampLabel.Enable = 'off';
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.BattleshipUIFigure);
            app.MissLamp.Enable = 'off';
            app.StartButton = uibutton(app.BattleshipUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.BattleshipUIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.NumberofShotsSpinnerLabel = uilabel(app.BattleshipUIFigure);
            app.NumberofShotsSpinnerLabel.Text = 'Number of Shots';
            app.NumberofShotsSpinner = uispinner(app.BattleshipUIFigure);
            app.NumberofShotsSpinner.Limits = [1 9];
            app.NumberofShotsSpinner.Value = 1;
            app.ControlsLabel = uilabel(app.BattleshipUIFigure);
            app.ControlsLabel.Text = 'Controls';
            app.FireButton = uibutton(app.BattleshipUIFigure, 'push');
            app.FireButton.ButtonPushedFcn = createCallbackFcn(app, @FireButtonPushed, true);
            app.FireButton.Enable = 'off';
            app.FireButton.Text = 'Fire!';
            app.DownButton = uibutton(app.BattleshipUIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Enable = 'off';
            app.DownButton.Text = 'Down';
            app.UpButton = uibutton(app.BattleshipUIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Enable = 'off';
            app.UpButton.Text = 'Up';
            app.RightButton = uibutton(app.BattleshipUIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Enable = 'off';
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.BattleshipUIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = 'Left';
            app.SettingsLabel = uilabel(app.BattleshipUIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.ShipsRemainingEditFieldLabel = uilabel(app.BattleshipUIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.BattleshipUIFigure, 'numeric');
            app.ShotsRemainingEditFieldLabel = uilabel(app.BattleshipUIFigure);
            app.ShotsRemainingEditFieldLabel.Text = 'Shots Remaining';
            app.ShotsRemainingEditField = uieditfield(app.BattleshipUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Battleship

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BattleshipUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BattleshipUIFigure)
        end
    end
end