classdef section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotButton                matlab.ui.control.Button
        FunctionDropDownLabel     matlab.ui.control.Label
        FunctionDropDown          matlab.ui.control.DropDown
        XAxisLabel                matlab.ui.control.Label
        XAxisLabelEditField       matlab.ui.control.EditField
        YAxisLabelEditFieldLabel  matlab.ui.control.Label
        YAxisLabelEditField       matlab.ui.control.EditField
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        PhaseEditFieldLabel       matlab.ui.control.Label
        PhaseEditField            matlab.ui.control.NumericEditField
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
        UIAxes                    matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function ButtonPushed(app, event)
            A = app.AmplitudeEditField.Value;
            F = app.FrequencyEditField.Value;
            P = app.PhaseEditField.Value;
            
            if strcmp(app.FunctionDropDown.Value,'sin') == 1
                x = [0:0.2:4*pi];
                y = A*sin(F*x+P);
                plot(app.UIAxes,x,y);
                app.UIAxes.Title.String = app.TitleEditField.Value;
                app.UIAxes.XLabel.String = app.XAxisLabelEditField.Value;
                app.UIAxes.YLabel.String = app.YAxisLabelEditField.Value;
            elseif strcmp(app.FunctionDropDown.Value,'cos') == 1
                x = [0:0.2:4*pi];
                y = A*cos(F*x+P);
                plot(app.UIAxes,x,y);
                app.UIAxes.Title.String = app.TitleEditField.Value;
                app.UIAxes.XLabel.String = app.XAxisLabelEditField.Value;
                app.UIAxes.YLabel.String = app.YAxisLabelEditField.Value;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'sin', 'cos'};
            app.FunctionDropDown.Value = 'sin';
            app.XAxisLabel = uilabel(app.UIFigure);
            app.XAxisLabel.Text = 'X-Axis Label';
            app.XAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YAxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisLabelEditFieldLabel.Text = 'Y-Axis Label';
            app.YAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end