classdef Battleship < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        StartGameButton               matlab.ui.control.Button
        QuitGameButton                matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        HITLampLabel                  matlab.ui.control.Label
        HITLamp                       matlab.ui.control.Lamp
        MISSLampLabel                 matlab.ui.control.Label
        MISSLamp                      matlab.ui.control.Lamp
        FIREButton                    matlab.ui.control.Button
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        BATTLESHIPLabel               matlab.ui.control.Label
        ErrorsTextArea                matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        a = arduino();
        board = [0,0,0;0,0,0;0,0,0];
        enemyships = [0,0,0;0,0,0;0,0,0];
        hits = [0,0,0;0,0,0;0,0,0];
        
        % Description
        % Description
    end
    

    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.board(2,2)=1;
            UpdateBoard(app.a,app.board)
            app.HITLamp.Enable = 0;
            app.MISSLamp.Enable = 0;
            shots = app.NumberofGuessesSpinner.Value;
            app.ShotsRemainingEditField.Value = shots;
            ships = app.NumberofShipsSpinner.Value;
            app.ShipsRemainingEditField.Value = ships;
            if app.NumberofGuessesSpinner.Value < app.NumberofShipsSpinner.Value
                app.ErrorsTextArea.Visible = 'on';
                pause(4)
                error('Error! You dont have enough guesses for number of ships. App Closed.');
            end
            
            while sum(sum(app.enemyships)) < app.NumberofShipsSpinner.Value
                rows = randi([1 3],1,1);
                cols = randi([1 3],1,1);
                app.enemyships(rows,cols) = 1;
            end
        end

        % Button pushed function: QuitGameButton
        function QuitGameButtonPushed(app, event)
            app.HITLamp.Enable=0;
            app.MISSLamp.Enable=0;
            app.board = [0,0,0;0,0,0;0,0,0];
            UpdateBoard(app.a,app.board)
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
            app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value - 1;
            
            
            [rows,cols] = find(app.board==1);
            
            if app.hits(rows,cols)==3
                app.ShotsRemainingEditField.Value=app.ShotsRemainingEditField.Value+1;
                app.ShipsRemainingEditField.Value=app.ShipsRemainingEditField.Value+1;
            end
            if app.hits(rows,cols)==2
                app.ShotsRemainingEditField.Value=app.ShotsRemainingEditField.Value+1;
                app.ShipsRemainingEditField.Value=app.ShipsRemainingEditField.Value;
            end
            if app.hits(rows,cols)==3
                app.ShotsRemainingEditField.Value=app.ShotsRemainingEditField.Value;
                app.ShipsRemainingEditField.Value=app.ShipsRemainingEditField.Value;
            end
            if app.enemyships(rows,cols) == 1
                app.hits(rows,cols)=3;
                app.ShipsRemainingEditField.Value = app.ShipsRemainingEditField.Value - 1;
                app.HITLamp.Enable = 1;
                pause(2)
                app.HITLamp.Enable = 0;
            else
                app.hits(rows,cols)=2;
                app.MISSLamp.Enable = 1;
                pause(2)
                app.MISSLamp.Enable = 0;
            end
            if app.ShipsRemainingEditField.Value == 0 && app.ShotsRemainingEditField.Value >= 0
                disp('VICTORY');
                % WIN PATTERN: Each row of LEDs lights up from in order starting form Left to right 
                % with a blinking time of .1 seconds. This pattern is repeated three times.
                for x = 1:3
                    app.board = [1,1,1;0,0,0;0,0,0];
                    UpdateBoard(app.a,app.board)
                    pause(.1)
                    app.board = [0,0,0;1,1,1;0,0,0];
                    UpdateBoard(app.a,app.board)
                    pause(.1)
                    app.board = [0,0,0;0,0,0;1,1,1];
                    UpdateBoard(app.a,app.board)
                    pause(.1)
                end
                app.board = [0,0,0;0,0,0;0,0,0];
                UpdateBoard(app.a,app.board)
            end
            if app.ShipsRemainingEditField.Value > 0 && app.ShotsRemainingEditField.Value == 0
                disp('DEFEAT');
                % LOSS PATTERN: Each column of LEDs lights up from in order starting form Left to right 
                % with a blinking time of .1 seconds. This pattern is repeated three times.
                for x = 1:3
                    app.board = [1,0,0;1,0,0;1,0,0];
                    UpdateBoard(app.a,app.board)
                    pause(.1)
                    app.board = [0,1,0;0,1,0;0,1,0];
                    UpdateBoard(app.a,app.board)
                    pause(.1)
                    app.board = [0,0,1;0,0,1;0,0,1];
                    UpdateBoard(app.a,app.board)
                    pause(.1)
                end
                app.board = [0,0,0;0,0,0;0,0,0];
                UpdateBoard(app.a,app.board)
            end
            
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            [rows,cols] = find(app.board == 1);
            if rows - 1 >= 1
                app.board(rows,cols)= 0;
                app.board(rows-1,cols)= 1;
                UpdateBoard(app.a,app.board)
            elseif rows - 1 < 1
                app.board(rows,cols) = 1;
                UpdateBoard(app.a,app.board);
            end
            if app.hits(rows,cols) ==3
                app.HITLamp.Enable=1;
                app.MISSLamp.Enable=0;
            elseif app.hits(rows,cols) ==2
                app.HITLamp.Enable=0;
                app.MISSLamp.Enable=1;
            else
                app.HITLamp.Enable=0;
                app.MISSLamp.Enable=0;
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            [rows,cols] = find(app.board == 1);
            if cols + 1 <= 3
                app.board(rows,cols)= 0;
                app.board(rows,cols+1)= 1;
                UpdateBoard(app.a,app.board)
            elseif cols + 1 > 3
                app.board(rows,cols) = 1;
                UpdateBoard(app.a,app.board);
            end
            if app.hits(rows,cols) ==3
                app.HITLamp.Enable=1;
                app.MISSLamp.Enable=0;
            elseif app.hits(rows,cols) ==2
                app.HITLamp.Enable=0;
                app.MISSLamp.Enable=1;
            else
                app.HITLamp.Enable=0;
                app.MISSLamp.Enable=0;
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            [rows,cols] = find(app.board == 1);
            if rows + 1 <= 3
                app.board(rows,cols)= 0;
                app.board(rows+1,cols)= 1;
                UpdateBoard(app.a,app.board)
            elseif rows + 1 > 3
                app.board(rows,cols) = 1;
                UpdateBoard(app.a,app.board);
            end
            if app.hits(rows,cols) ==3
                app.HITLamp.Enable=1;
                app.MISSLamp.Enable=0;
            elseif app.hits(rows,cols) ==2
                app.HITLamp.Enable=0;
                app.MISSLamp.Enable=1;
            else
                app.HITLamp.Enable=0;
                app.MISSLamp.Enable=0;
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            [rows,cols] = find(app.board == 1);
            if cols - 1 >= 1
                app.board(rows,cols)= 0;
                app.board(rows,cols-1)= 1;
                UpdateBoard(app.a,app.board)
            elseif cols - 1 < 1
                app.board(rows,cols) = 1;
                UpdateBoard(app.a,app.board);
            end
            if app.hits(rows,cols) ==3
                app.HITLamp.Enable=1;
                app.MISSLamp.Enable=0;
            elseif app.hits(rows,cols) ==2
                app.HITLamp.Enable=0;
                app.MISSLamp.Enable=1;
            else
                app.HITLamp.Enable=0;
                app.MISSLamp.Enable=0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Value = 1;
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.QuitGameButton = uibutton(app.UIFigure, 'push');
            app.QuitGameButton.ButtonPushedFcn = createCallbackFcn(app, @QuitGameButtonPushed, true);
            app.QuitGameButton.Text = 'Quit Game';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.FIREButton = uibutton(app.UIFigure, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Text = 'FIRE!';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.HITLampLabel = uilabel(app.UIFigure);
            app.HITLampLabel.Text = 'HIT';
            app.HITLamp = uilamp(app.UIFigure);
            app.MISSLampLabel = uilabel(app.UIFigure);
            app.MISSLampLabel.Text = 'MISS';
            app.MISSLamp = uilamp(app.UIFigure);
            app.ShipsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShotsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShotsRemainingEditFieldLabel.Text = 'Shots Remaining';
            app.ShotsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.BATTLESHIPLabel = uilabel(app.UIFigure);
            app.BATTLESHIPLabel.Text = 'BATTLESHIP';
            app.ErrorsTextArea = uitextarea(app.UIFigure);
            app.ErrorsTextArea.Visible = 'off';
            app.ErrorsTextArea.Value = {'Error! You dont have enough guesses for number of ships. Closing app...'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Battleship

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end