classdef section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        FunctionDropDownLabel     matlab.ui.control.Label
        FunctionDropDown          matlab.ui.control.DropDown
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        PhaseAngleEditFieldLabel  matlab.ui.control.Label
        PhaseAngleEditField       matlab.ui.control.NumericEditField
        XAxisLabelEditFieldLabel  matlab.ui.control.Label
        XAxisLabelEditField       matlab.ui.control.EditField
        YAxisLabelEditFieldLabel  matlab.ui.control.Label
        YAxisLabelEditField       matlab.ui.control.EditField
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
           Amplitude = app.AmplitudeEditField.Value;
           Frequency = app.FrequencyEditField.Value;
           PhaseAngle = app.PhaseAngleEditField.Value;
           x = 0:.01:4*pi;
           Sin = Amplitude*sin(Frequency*x + PhaseAngle);
           Cos = Amplitude*cos(Frequency*x + PhaseAngle);
           
           if app.FunctionDropDown.Value == 'Sin'
               plot(app.UIAxes,x,Sin)
               xlim(app.UIAxes,[0 4*pi])
               ylim(app.UIAxes,[-4 4])
               xlabel(app.UIAxes,app.XAxisLabelEditField.Value)
               ylabel(app.UIAxes,app.YAxisLabelEditField.Value)
               title(app.UIAxes,app.TitleEditField.Value)
           elseif app.FunctionDropDown.Value == 'Cos'
               plot(app.UIAxes,x,Cos)
               xlim(app.UIAxes,[0 4*pi])
               ylim(app.UIAxes,[-4 4])
               xlabel(app.UIAxes,app.XAxisLabelEditField.Value)
               ylabel(app.UIAxes,app.YAxisLabelEditField.Value)
               title(app.UIAxes,app.TitleEditField.Value)
           end   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'Sin', 'Cos'};
            app.FunctionDropDown.Value = 'Sin';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseAngleEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseAngleEditFieldLabel.Text = 'Phase Angle';
            app.PhaseAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.XAxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.XAxisLabelEditFieldLabel.Text = 'X-Axis Label';
            app.XAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YAxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisLabelEditFieldLabel.Text = 'Y-Axis Label';
            app.YAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end