classdef Section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InsertPlotButton             matlab.ui.control.Button
        FunctionDropDownLabel        matlab.ui.control.Label
        FunctionDropDown             matlab.ui.control.DropDown
        FrequencyEditFieldLabel      matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        AmplitudeEditField           matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel     matlab.ui.control.Label
        PhaseShiftEditField          matlab.ui.control.NumericEditField
        TitleEditFieldLabel          matlab.ui.control.Label
        TitleEditField               matlab.ui.control.EditField
        XaxisEditFieldLabel          matlab.ui.control.Label
        XaxisEditField               matlab.ui.control.EditField
        YaxisEditFieldLabel          matlab.ui.control.Label
        YaxisEditField               matlab.ui.control.EditField
        FunctionTranformationsLabel  matlab.ui.control.Label
        GraphEditingLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
     A = 1
     B = 1
     C = 0
     Title = ''
     Yaxis = ''
     Xaxis = ''
    end
    

    methods (Access = private)

        % Button pushed function: InsertPlotButton
        function InsertPlotButtonGraph(app, event)
        x = linspace(0,4*pi);
        value = app.FunctionDropDown.Value; 
        switch value 
            case 'sin'
                y = app.A*sin(app.B*x + app.C);
            case 'cos'
                y = app.A*cos(app.B*x + app.C);        
        end
        xlabel(app.UIAxes, app.Xaxis);
        ylabel(app.UIAxes, app.Yaxis);
        title(app.UIAxes, app.Title);
        plot(app.UIAxes,y)
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.A = app.AmplitudeEditField.Value;
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.B = app.FrequencyEditField.Value;
        end

        % Value changed function: PhaseShiftEditField
        function PhaseShiftEditFieldValueChanged(app, event)
            app.C = app.PhaseShiftEditField.Value;
        end

        % Value changed function: YaxisEditField
        function YaxisEditFieldValueChanged(app, event)
            app.Yaxis = app.YaxisEditField.Value;
        end

        % Value changed function: XaxisEditField
        function XaxisEditFieldValueChanged(app, event)
            app.Xaxis = app.XaxisEditField.Value;
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            app.Title = app.TitleEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InsertPlotButton = uibutton(app.UIFigure, 'push');
            app.InsertPlotButton.ButtonPushedFcn = createCallbackFcn(app, @InsertPlotButtonGraph, true);
            app.InsertPlotButton.Text = 'Insert Plot';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'sin', 'cos'};
            app.FunctionDropDown.ItemsData = {'sin', 'cos', ''};
            app.FunctionDropDown.Value = 'sin';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.PhaseShiftEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShiftEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseShiftEditFieldValueChanged, true);
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title ';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.XaxisEditFieldLabel = uilabel(app.UIFigure);
            app.XaxisEditFieldLabel.Text = 'X-axis';
            app.XaxisEditField = uieditfield(app.UIFigure, 'text');
            app.XaxisEditField.ValueChangedFcn = createCallbackFcn(app, @XaxisEditFieldValueChanged, true);
            app.YaxisEditFieldLabel = uilabel(app.UIFigure);
            app.YaxisEditFieldLabel.Text = 'Y-axis';
            app.YaxisEditField = uieditfield(app.UIFigure, 'text');
            app.YaxisEditField.ValueChangedFcn = createCallbackFcn(app, @YaxisEditFieldValueChanged, true);
            app.FunctionTranformationsLabel = uilabel(app.UIFigure);
            app.FunctionTranformationsLabel.Text = 'Function Tranformations';
            app.GraphEditingLabel = uilabel(app.UIFigure);
            app.GraphEditingLabel.Text = 'Graph Editing';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end