classdef Section4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumberofShipsSpinnerLabel       matlab.ui.control.Label
        NumberofShipsSpinner            matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel     matlab.ui.control.Label
        NumberofGuessesSpinner          matlab.ui.control.Spinner
        HitLampLabel                    matlab.ui.control.Label
        HitLamp                         matlab.ui.control.Lamp
        MissLampLabel                   matlab.ui.control.Label
        MissLamp                        matlab.ui.control.Lamp
        ShootButton                     matlab.ui.control.Button
        UPButton                        matlab.ui.control.Button
        LEFTButton                      matlab.ui.control.Button
        RIGHTButton                     matlab.ui.control.Button
        DOWNButton                      matlab.ui.control.Button
        ShipsRemainingEditFieldLabel    matlab.ui.control.Label
        ShipsRemainingEditField         matlab.ui.control.NumericEditField
        GuessesRemainingEditFieldLabel  matlab.ui.control.Label
        GuessesRemainingEditField       matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        QuitButton                      matlab.ui.control.Button
        NearLampLabel                   matlab.ui.control.Label
        NearLamp                        matlab.ui.control.Lamp
        FarLampLabel                    matlab.ui.control.Label
        FarLamp                         matlab.ui.control.Lamp
        DisplayTextAreaLabel            matlab.ui.control.Label
        DisplayTextArea                 matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        GAMEON = 0; % Game Start Condition, Default is Game is off
        a = arduino() % Arduino object
        pins = ["D12","D11","D10";"D9","D8","D7";"D6","D5","D4"]; % String Matrix for UpdateBoard
        board = [0,0,0;0,0,0;0,0,0]; %User's Position Matrix
        Guesses = 0; %Guesses Left for the user
        Ships = 0; % Ships Left for the user
        LitRow = 2; %User's Position Row
        LitCol = 2; %User's Position Column 
        Sea = [0,0,0;0,0,0;0,0,0]; % Matrix for ship's location and  level of status: (0,1,2,3)
    end
    

    methods (Access = private)

        % Button pushed function: LEFTButton
        function LEFT(app, event)
          %Test to make sure start game conditions are met
            if app.GAMEON == 1
                % Board value turns off and the position column-1 (Left) is set to 1.
                % if row value is out of 3x3 matrix, set it to row 1.
                % Updates Board to reflect movement
                app.board(app.LitRow,app.LitCol) = 0;
                app.LitCol = app.LitCol - 1;
                %Error for out of bounds
                if app.LitCol < 1
                    app.LitCol = 1;
                    app.DisplayTextArea.Value = 'At Boundary, Cannot Go Left!';
                end
                app.board(app.LitRow,app.LitCol) = 1;
                UpdateBoard(app.a,app.pins,app.board)
            end
        end

        % Button pushed function: RIGHTButton
        function RIGHT(app, event)
            %Test to make sure start game conditions are met
            if app.GAMEON == 1
                % Board value turns off and the position column+1 (RIGHT) is set to 1.
                % if row value is out of 3x3 matrix, set it to  3.
                % Updates Board to reflect movement
                app.board(app.LitRow,app.LitCol) = 0;
                app.LitCol = app.LitCol + 1;
                %Error for out of bounds
                if app.LitCol >3
                    app.LitCol = 3;
                    app.DisplayTextArea.Value = 'At Boundary, Cannot Go Right!';
                end
                app.board(app.LitRow,app.LitCol) = 1;
                UpdateBoard(app.a,app.pins,app.board)
            end
        end

        % Button pushed function: DOWNButton
        function DOWN(app, event)
         %Test to make sure start game conditions are met
            if app.GAMEON == 1
                % Board value turns off and the position row+1 (DOWN) is set to 1.
                % if row value is out of 3x3 matrix, set it to  3.
                % Updates Board to reflect movement 
                app.board(app.LitRow,app.LitCol) = 0;
                app.LitRow = app.LitRow + 1;
                %Error for out of bounds
                if app.LitRow > 3
                    app.LitRow = 3;
                    app.DisplayTextArea.Value = 'At Boundary, Cannot Go Down!';
                end
                app.board(app.LitRow,app.LitCol) = 1;
                UpdateBoard(app.a,app.pins,app.board)
            end
        end

        % Button pushed function: StartButton
        function START(app, event)
           %Makes sure Player cannot starts the game twice, or turn two lights on at the same time
            if sum(sum(app.board)) < 1 
                %Set Lamps off by default
                app.HitLamp.Enable = 'off';
                app.MissLamp.Enable = 'off';
                app.NearLamp.Enable = 'off';
                app.FarLamp.Enable = 'off';
                %Assign Guesses and Ships to initial parameters 
                app.Guesses = app.NumberofGuessesSpinner.Value;
                app.Ships = app.NumberofShipsSpinner.Value;
                %Error Condition to prevent instant loss
                if app.Guesses < app.Ships
                    app.Guesses = 0;
                    app.Ships = 0;
                    app.DisplayTextArea.Value = 'Not Enough Guesses To Play!';
                end
                %Assign Edit Field to Initial Values
                app.GuessesRemainingEditField.Value = app.Guesses;
                app.ShipsRemainingEditField.Value = app.Ships;
                
                % Game Start Condition
                if app. Ships > 0
                    %Turn on Board, Assign Ship's Position, Turn on Cursor, Starts Game 
                    app.GAMEON = 1;
                    app.LitCol = 2;
                    app.LitRow = 2;
                    app.board(app.LitRow,app.LitCol) = 1;
                    UpdateBoard(app.a,app.pins,app.board)
                  %Make Sure Ships are in Unique Locations 
                    I = app.Ships;
                    for c = 1:1:I
                        Position = randi([1 9]);
                        while app.Sea(Position) == 1
                            Position = randi([1 9]);
                        end
                        app.Sea(Position) = 1;
                    end
                    %Have Fun Display
                    app.DisplayTextArea.Value = 'Have Fun!';
                end
            end
        end

        % Button pushed function: QuitButton
        function QUIT(app, event)
            if app.GAMEON == 1
              %Goodbye Display
                app.DisplayTextArea.Value = 'Goodbye!';
                pause(1)
                app.DisplayTextArea.Value = '';
            end
            
            %Resets game 
            app.GAMEON = 0;
            app.board = zeros([3 3]);
            app.Sea = zeros([3 3]);
            UpdateBoard(app.a,app.pins,app.board)
            clc
            clear all
        end

        % Button pushed function: ShootButton
        function SHOOT(app, event)
          %Ensures Button doesn't work if game start conditions aren't met.
            if app.GAMEON == 1

                %            ***Testing Adjecent Cells*** 
                % I'm Creating A Vector Test Which has the tranformations for going left,right,up and down.
                % I use a for loop to travel down Test and make sure it's within a 3x3 Matrix.
                % If it is I test to see if there is a ship that hasn't been sunk (app.Sea(ROW,COL) == 1)
                % If that condition is met for any Adjacent Position, Flag = 1
                L = app.LitCol-1;
                R = app.LitCol+1;
                U = app.LitRow-1;
                D = app.LitRow+1;
                Test = [L,R,U,D];
                Flag = 0;
                for x = 1:1:4
                    if Test(x) >= 1 && Test(x) <= 3
                        if x <= 2
                            if app.Sea(app.LitRow,Test(x)) == 1
                                Flag = 1;
                            end
                        else
                            if app.Sea(Test(x),app.LitCol) == 1
                                Flag = 1;
                            end
                        end
                    end
                end
                                %               ***Testing User's Shot***
                %This IF statement determines whether the condition of Matrix Sea at User's Location.
                % app.Sea(ROW,COL) == 1;
                    % User sinks ship. Hit Lamp Glows. Reduces Guesses and Ships by one. Set Sea to 3 (Sunken Ship).
                % app.Sea(ROW,COL) == 0;
                    % User Misses. Miss Lamp Glows Reduces Guesses by on. Sets Sea to 2 (Missed Location Previously Guessed).
                % app.Sea(ROW,COL) == 3; *Used in Display*
                    % User Guesses Sunken Ship Again. Hit Lamp Glows but nothing else.
                % app.Sea(ROW,COL) == 2; *Used in Display*
                    % User Guesses Missed Location Again. Miss Lamp but nothing else
                
                %           ***Display****
                % If the Flag is triggered, 4 unique responses are created to signal that a ship is nearby.
                % If the Flag is not Triggered, 4 unique repsonses are created to signal that no ship is nearby
                % For Both Conditions, the 4 unique responses are for:
                % For a Hit (app.Sea(ROW,COL) == 1). Hit Light is on.
                % For a Miss (app.Sea(ROW,COL) == 0). Miss Light is on.
                % If Hit was already guessed (app.Sea(ROW,COL) == 3). Hit Light is on.
                % If Miss was already guessed (app.Sea(ROW,COL) == 2). Miss Light is on.
                % Conditions 0,1,2,3 are determined by ***Testing User's Shot*** if statement, above.
                if Flag == 1
                    if app.Sea(app.LitRow,app.LitCol) == 0
                        app.DisplayTextArea.Value = 'Miss, but Close!';
                        app.NearLamp.Enable = 'on';
                        app.MissLamp.Enable = 'on';
                        pause(1)
                        app.NearLamp.Enable = 'off';
                        app.MissLamp.Enable = 'off';
                                      app.Guesses = app.Guesses - 1;
                    app.Sea(app.LitRow,app.LitCol) = 2;
                    elseif Flag == 1 && app.Sea(app.LitRow,app.LitCol) == 1
                        app.DisplayTextArea.Value = 'Nice Shot! More Are Adjacent!';
                        app.NearLamp.Enable = 'on';
                        app.HitLamp.Enable = 'on';
                        pause(1)
                        app.HitLamp.Enable = 'off';
                        app.NearLamp.Enable = 'off';
                                            app.Guesses = app.Guesses - 1;
                    app.Ships = app.Ships - 1;
                    app.Sea(app.LitRow,app.LitCol) = 3;
                    elseif Flag == 1 && app.Sea(app.LitRow,app.LitCol) == 2
                        app.DisplayTextArea.Value = 'You Already Missed Here! More Are Adjacent!';
                        app.NearLamp.Enable = 'on';
                        app.MissLamp.Enable = 'on';
                        pause(1)
                        app.NearLamp.Enable = 'off';
                        app.MissLamp.Enable = 'off';
                    elseif Flag == 1 && app.Sea(app.LitRow,app.LitCol) == 3
                        app.DisplayTextArea.Value = 'This Ship Is Already Sunk! More Are Adjacent!';
                        app.NearLamp.Enable = 'on';
                        app.HitLamp.Enable = 'on';
                        pause(1)
                        app.HitLamp.Enable = 'off';
                        app.NearLamp.Enable = 'off';
                    end
                elseif Flag == 0
                    if app.Sea(app.LitRow,app.LitCol) == 0
                        app.DisplayTextArea.Value = 'Are You Aiming For The Water?';
                        app.FarLamp.Enable = 'on';
                        app.MissLamp.Enable = 'on';
                        pause(1)
                        app.FarLamp.Enable = 'off';
                        app.MissLamp.Enable = 'off';
                                      app.Guesses = app.Guesses - 1;
                    app.Sea(app.LitRow,app.LitCol) = 2;
                    elseif app.Sea(app.LitRow,app.LitCol) == 1
                        app.DisplayTextArea.Value = 'Wow a Lucky Shot! None Are Adjacent!';
                        app.FarLamp.Enable = 'on';
                        app.HitLamp.Enable = 'on';
                        pause(1)
                        app.FarLamp.Enable = 'off';
                        app.HitLamp.Enable = 'off';
                                            app.Guesses = app.Guesses - 1;
                    app.Ships = app.Ships - 1;
                    app.Sea(app.LitRow,app.LitCol) = 3;
                    elseif app.Sea(app.LitRow,app.LitCol) == 2
                        app.DisplayTextArea.Value = 'You Already Missed Here! None Are Adjacent!';
                        app.FarLamp.Enable = 'on';
                        app.MissLamp.Enable = 'on';
                        pause(1)
                        app.FarLamp.Enable = 'off';
                        app.MissLamp.Enable = 'off';
                    elseif app.Sea(app.LitRow,app.LitCol) == 3
                        app.DisplayTextArea.Value = 'This Ship Is Already Sunk! None Are Adjacent!';
                        app.FarLamp.Enable = 'on';
                        app.HitLamp.Enable = 'on';
                        pause(1)
                        app.FarLamp.Enable = 'off';
                        app.HitLamp.Enable = 'off';
                    end
                   
                end
                                 % Changing Values of Edit Field 
                app.GuessesRemainingEditField.Value = app.Guesses;
                app.ShipsRemainingEditField.Value = app.Ships;
                
                %           ****Win/Loss Scenarios****
                % A win is if all ships are sunk before player runs out of guesses (Including winning at last guess)
                % Lights in Position 1,8,2 will blink on and off 20 times (For Blink 182)
                % "You Win! :)" will display in display and in command window 
                % A loss is if ships are remaining and player runs out of guesses
                % Lights in Position 1,3,5,7,9 will turn on and blink 5 times (Frowny Face) 
                % "You Lost! :(" will display in display and in command window
                if app.Ships > app.Guesses && app.Guesses == 0
                    fprintf('You Lost! :(')
                    app.DisplayTextArea.Value = 'You Lost! :(';
                    app.board = zeros([3 3]);
                    UpdateBoard(app.a,app.pins,app.board)
                    app.board(1) = 1;
                    UpdateBoard(app.a,app.pins,app.board)
                    pause(0.05)
                    app.board(3) = 1;
                    UpdateBoard(app.a,app.pins,app.board)
                    pause(0.05)
                    app.board(5) = 1;
                    UpdateBoard(app.a,app.pins,app.board)
                    pause(0.05)
                    app.board(7) = 1;
                    UpdateBoard(app.a,app.pins,app.board)
                    pause(0.05)
                    app.board(9) = 1;
                    UpdateBoard(app.a,app.pins,app.board)
                    for c = 1:5
                        app.board = zeros([3 3]);
                        UpdateBoard(app.a,app.pins,app.board)
                        app.board(1) = 1 ;
                        app.board(3) = 1 ;
                        app.board(5) = 1 ;
                        app.board(7) = 1 ;
                        app.board(9) = 1 ;
                        UpdateBoard(app.a,app.pins,app.board)
                        pause(0.5)
                    end
                    app.GAMEON = 0;
                elseif app.Guesses >= app.Ships && app.Ships == 0
                    fprintf('You Win! :)')
                    app.DisplayTextArea.Value = 'You Win! :)';
                    for c = 1:20
                        app.board = zeros([3 3]);
                        UpdateBoard(app.a,app.pins,app.board)
                        app.board(1) = 1;
                        UpdateBoard(app.a,app.pins,app.board)
                        pause(0.05)
                        app.board(8) = 1;
                        UpdateBoard(app.a,app.pins,app.board)
                        pause(0.05)
                        app.board(2) = 1;
                        UpdateBoard(app.a,app.pins,app.board)
                        pause(0.05)
                    end
                end
            end
        end

        % Button pushed function: UPButton
        function UP(app, event)
        % Test to make sure start game conditions are met
            if app.GAMEON == 1
                % Board value turns off and the position row-1 (UP) is set to 1.
                % if row value is out of 3x3 matrix, set it to row 1.
                % Updates Board to reflect movement
                app.board(app.LitRow,app.LitCol) = 0;
                app.LitRow = app.LitRow - 1;
                %Error for out of bounds
                if app.LitRow < 1
                    app.LitRow = 1;
                    app.DisplayTextArea.Value = 'At Boundary, Cannot Go Up!';
                end
                app.board(app.LitRow,app.LitCol) = 1;
                UpdateBoard(app.a,app.pins,app.board)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Editable = 'off';
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Editable = 'off';
            app.NumberofGuessesSpinner.Value = 1;
            app.HitLampLabel = uilabel(app.UIFigure);
            app.HitLampLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.UIFigure);
            app.MissLampLabel = uilabel(app.UIFigure);
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.UIFigure);
            app.ShootButton = uibutton(app.UIFigure, 'push');
            app.ShootButton.ButtonPushedFcn = createCallbackFcn(app, @SHOOT, true);
            app.ShootButton.Text = 'Shoot';
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UP, true);
            app.UPButton.Text = 'UP';
            app.LEFTButton = uibutton(app.UIFigure, 'push');
            app.LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @LEFT, true);
            app.LEFTButton.Text = {'LEFT'; ''};
            app.RIGHTButton = uibutton(app.UIFigure, 'push');
            app.RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHT, true);
            app.RIGHTButton.Text = {'RIGHT'; ''};
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWN, true);
            app.DOWNButton.Text = 'DOWN';
            app.ShipsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.GuessesRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.GuessesRemainingEditFieldLabel.Text = 'Guesses Remaining';
            app.GuessesRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @START, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QUIT, true);
            app.QuitButton.Text = 'Quit';
            app.NearLampLabel = uilabel(app.UIFigure);
            app.NearLampLabel.Text = 'Near';
            app.NearLamp = uilamp(app.UIFigure);
            app.FarLampLabel = uilabel(app.UIFigure);
            app.FarLampLabel.Text = 'Far';
            app.FarLamp = uilamp(app.UIFigure);
            app.DisplayTextAreaLabel = uilabel(app.UIFigure);
            app.DisplayTextAreaLabel.Text = 'Display';
            app.DisplayTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Section4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end