classdef BattleshipGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BattleShip_GUI                matlab.ui.Figure
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        SettingsLabel                 matlab.ui.control.Label
        UpButton                      matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        ControlPanelLabel             matlab.ui.control.Label
        HitLampLabel                  matlab.ui.control.Label
        HitLamp                       matlab.ui.control.Lamp
        MissLampLabel                 matlab.ui.control.Label
        MissLamp                      matlab.ui.control.Lamp
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
        MissionStatusLabel            matlab.ui.control.Label
        ImportantButtonsLabel         matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        a = arduino; 
        pins = ["D12" "D11" "D10"
        "D9" "D8" "D7"
        "D6" "D5" "D4"];
    r = 2;
    c = 2;
          
    ships1 = [0 1 0; 0 0 0; 0 0 0];

    ships2 = [0 1 0; 0 0 0; 1 0 0];
          
    ships3 = [0 0 0; 0 1 0; 0 1 1];
           
    ships4 = [0 1 0; 1 0 1; 0 1 0];
    ships = [0 0 0; 0 0 0; 0 0 0];
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
          
            writeDigitalPin(app.a,app.pins(app.r,app.c),1)
            app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner.Value;
           app.ShotsRemainingEditField.Value = app.NumberofGuessesSpinner.Value;
           while app.NumberofShipsSpinner.Value > app.NumberofGuessesSpinner.Value
               error('Number of ships cannot be greater than guesses')
               disp('Select another value for guesses')
               app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner.Value;
               app.ShotsRemainingEditField.Value = app.NumberofGuessesSpinner.Value;
               writeDigitalPin(app.a,app.pins(app.r,app.c),0)
           end
           set(app.UpButton,'enable','on')
           set(app.SelectButton,'enable','on')
           set(app.DownButton,'enable','on')
           set(app.RightButton,'enable','on')
           set(app.LeftButton,'enable','on')
           set(app.HitLamp,'enable','off')
           set(app.MissLamp,'enable','off')
           if app.NumberofShipsSpinner.Value == 1
               app.ships = app.ships1; 
           elseif app.NumberofShipsSpinner.Value == 2
               app.ships = app.ships2;
           elseif app.NumberofShipsSpinner.Value ==3
               app.ships = app.ships3; 
           else
               app.ships = app.ships4; 
           end
                
           
          uiwait
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
         uiresume
         app.r = app.r -1;
         while app.r < 1
             warning('Cannot exceed boundaries')
             app.r = 1;
         end
         writeDigitalPin(app.a,app.pins(app.r,app.c),1)
         writeDigitalPin(app.a,app.pins(app.r+1,app.c),0)
         set(app.HitLamp,'enable','off')
         set(app.MissLamp,'enable','off')
         
         uiwait
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            uiresume 
            app.r = app.r+1;
            while app.r >3
                warning('Cannot exceed the boundaries')
                app.r = 3;
            end
            writeDigitalPin(app.a, app.pins(app.r,app.c),1)
            writeDigitalPin(app.a, app.pins(app.r-1,app.c),0)
            set(app.HitLamp,'enable','off')
            set(app.MissLamp,'enable','off')
            
            uiwait
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            uiresume 
            app.c = app.c +1;
            while app.c >3
                warning('Cannot exceed the boundaries')
                app.c = 3;
            end
            writeDigitalPin(app.a, app.pins(app.r,app.c),1)
            writeDigitalPin(app.a,app.pins(app.r,app.c-1),0)
            set(app.HitLamp,'enable','off')
            set(app.MissLamp,'enable','off')
            
            uiwait
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            uiresume 
            app.c = app.c -1;
            while app.c <1
                warning('Cannot exceed boundaries')
                app.c = 1;
            end
            writeDigitalPin(app.a,app.pins(app.r,app.c),1)
            writeDigitalPin(app.a,app.pins(app.r,app.c+1),0)
            set(app.HitLamp,'enable','off')
            set(app.MissLamp,'enable','off')
            uiwait
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
           set(app.UpButton,'enable','off')
           set(app.SelectButton,'enable','off')
           set(app.DownButton,'enable','off')
           set(app.RightButton,'enable','off')
           set(app.LeftButton,'enable','off')
           app.r = 2;
           app.c =2;
           for x = 1:1:9
               writeDigitalPin(app.a,app.pins(x),0)
           end
           uiwait
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            uiresume
            if app.ships(app.r,app.c) ==1 
                set(app.HitLamp,'enable','on')
                
                
                set(app.MissLamp,'enable','off')
                
                app.ships(app.r,app.c) = 0;
                app.ShipsRemainingEditField.Value = app.ShipsRemainingEditField.Value - 1;
                app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value -1;
                if app.ShotsRemainingEditField.Value <0
                    app.ShotsRemainingEditField.Value = 0;
                end
            elseif app.ships(app.r,app.c) ==0
                set(app.MissLamp,'enable','on')
                
                set(app.HitLamp,'enable','off')
                
                app.ShotsRemainingEditField.Value =app.ShotsRemainingEditField.Value-1;    
            end
       
           uiresume
            if app.ShipsRemainingEditField.Value ==0
               
               disp('CONGRATULATIONS YOU WON')
               disp('START ANOTHER GAME OR PRESS QUIT')
               disp('All the LEDs will remain on after blinking thrice')
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),1)
               end
               pause(1)
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),0)
               end
               pause(1)
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),1)
               end
               pause(1)
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),0)
               end
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),1)
               end  
               uiwait
           elseif app.ShotsRemainingEditField.Value ==0
               disp('SORRY, YOU LOST')
               disp('PLEASE QUIT THE GAME')
               disp('All the LEDs will blink twice')
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),1)
               end
               pause(1)
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),0)
               end
               pause(1)
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),1)
               end
               pause(1)
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),0)
               end
               
           elseif app.ShotsRemainingEditField.Value ==0 && app.ShipsRemainingEditField.Value==0
               disp('CONGRATULATIONS YOU WON')
               disp('START ANOTHER GAME OR PRESS QUIT')
               disp('All the LEDs will remain on after blinking thrice')
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),1)
               end
               pause(1)
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),0)
               end
               pause(1)
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),1)
               end
               pause(1)
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),0)
               end
               for l = 1:1:9
                   writeDigitalPin(app.a,app.pins(l),1)
               end 
               uiwait
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BattleShip_GUI = uifigure;
            app.BattleShip_GUI.Name = 'UI Figure';
            app.NumberofShipsSpinnerLabel = uilabel(app.BattleShip_GUI);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships ';
            app.NumberofShipsSpinner = uispinner(app.BattleShip_GUI);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofGuessesSpinnerLabel = uilabel(app.BattleShip_GUI);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of  Guesses';
            app.NumberofGuessesSpinner = uispinner(app.BattleShip_GUI);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Value = 1;
            app.SettingsLabel = uilabel(app.BattleShip_GUI);
            app.SettingsLabel.Text = 'Settings';
            app.UpButton = uibutton(app.BattleShip_GUI, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Enable = 'off';
            app.UpButton.Text = 'Up';
            app.SelectButton = uibutton(app.BattleShip_GUI, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.DownButton = uibutton(app.BattleShip_GUI, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Enable = 'off';
            app.DownButton.Text = 'Down';
            app.RightButton = uibutton(app.BattleShip_GUI, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Enable = 'off';
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.BattleShip_GUI, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = 'Left';
            app.ControlPanelLabel = uilabel(app.BattleShip_GUI);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.HitLampLabel = uilabel(app.BattleShip_GUI);
            app.HitLampLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.BattleShip_GUI);
            app.MissLampLabel = uilabel(app.BattleShip_GUI);
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.BattleShip_GUI);
            app.ShipsRemainingEditFieldLabel = uilabel(app.BattleShip_GUI);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.BattleShip_GUI, 'numeric');
            app.ShotsRemainingEditFieldLabel = uilabel(app.BattleShip_GUI);
            app.ShotsRemainingEditFieldLabel.Text = 'Shots Remaining';
            app.ShotsRemainingEditField = uieditfield(app.BattleShip_GUI, 'numeric');
            app.MissionStatusLabel = uilabel(app.BattleShip_GUI);
            app.MissionStatusLabel.Text = 'Mission Status';
            app.ImportantButtonsLabel = uilabel(app.BattleShip_GUI);
            app.ImportantButtonsLabel.Text = 'Important Buttons';
            app.StartButton = uibutton(app.BattleShip_GUI, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.BattleShip_GUI, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BattleshipGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BattleShip_GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BattleShip_GUI)
        end
    end
end