classdef Battleship < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofTorpedoesSpinnerLabel  matlab.ui.control.Label
        NumberofTorpedoesSpinner       matlab.ui.control.Spinner
        NumberofShipsSpinnerLabel      matlab.ui.control.Label
        NumberofShipsSpinner           matlab.ui.control.Spinner
        FEUERButton                    matlab.ui.control.Button
        UPButton                       matlab.ui.control.Button
        RIGHTButton                    matlab.ui.control.Button
        LEFTButton                     matlab.ui.control.Button
        DOWNButton                     matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        HALTButton                     matlab.ui.control.Button
        HITLampLabel                   matlab.ui.control.Label
        HITLamp                        matlab.ui.control.Lamp
        MISSLampLabel                  matlab.ui.control.Label
        MISSLamp                       matlab.ui.control.Lamp
        EINSTELLUNGENLabel             matlab.ui.control.Label
        ZUSTANDLabel                   matlab.ui.control.Label
        SCHALTTAFELLabel               matlab.ui.control.Label
        MOTORSTARTLabel                matlab.ui.control.Label
        SHIPSREMAININGEditFieldLabel   matlab.ui.control.Label
        SHIPSREMAININGEditField        matlab.ui.control.NumericEditField
        ATTEMPTSREMAININGEditFieldLabel  matlab.ui.control.Label
        ATTEMPTSREMAININGEditField     matlab.ui.control.NumericEditField
        NEARMISSLampLabel              matlab.ui.control.Label
        NEARMISSLamp                   matlab.ui.control.Lamp
        FARMISSLampLabel               matlab.ui.control.Label
        FARMISSLamp                    matlab.ui.control.Lamp
        WARNUNGTextAreaLabel           matlab.ui.control.Label
        WARNUNGTextArea                matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        row % writeable row #
        col % writeable column #
        pins % Pin matrix
        board % Board matrix
        a % arduino
        ocean % Map of ships in the target area
        firecontrol % Fire Director's shot map
        hitormiss % I guess they never miss, huh?
        rr % Random Row
        rc % Random Column
        vic % Victory strobe pattern, a cross canted on its side
        def % Defeat strobe pattern, 2 horizontal stripes
        def2 % Other half of defeat strobe pattern, 1 horizontal stripe
        x % value used to control victory/defeat patterns
        BLock % Locks the FEUER and Directional Buttons until START is pressed
        nearmiss % Map to keep track of near and far misses
        
    end
    

    methods (Access = private)

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            if app.BLock == 0
                return
            end
            app.HITLamp.Enable = 'off';
            app.MISSLamp.Enable = 'off';
            app.NEARMISSLamp.Enable = 'off';
            app.FARMISSLamp.Enable = 'off';
            if app.row -1 > 0
                app.board = zeros([3,3]);
                app.row = app.row - 1;
                app.board(app.row,app.col) = 1;
                UpdateBoard(app.a,app.pins,app.board);
            end
            if app.hitormiss(app.row,app.col) == 1
                app.MISSLamp.Enable = 'on';
                
            end
            if app.hitormiss(app.row,app.col) == 2
                app.HITLamp.Enable = 'on';
            end
            if app.nearmiss(app.row, app.col) == 2
                app.NEARMISSLamp.Enable = 'on';
            end
            if app.nearmiss(app.row,app.col) == 1
                app.FARMISSLamp.Enable = 'on';
            end
            if app.hitormiss(app.row,app.col) == 2
                app.FARMISSLamp.Enable = 'off';
            end
        end

        % Button pushed function: RIGHTButton
        function RIGHTButtonPushed(app, event)
            if app.BLock == 0
                return
            end
            app.HITLamp.Enable = 'off';
            app.MISSLamp.Enable = 'off';
            app.NEARMISSLamp.Enable = 'off';
            app.FARMISSLamp.Enable = 'off';
            if app.col + 1 < 4
                app.board = zeros([3,3]);
                app.col = app.col + 1;
                app.board(app.row,app.col) = 1;
                UpdateBoard(app.a,app.pins,app.board);
            end
            if app.hitormiss(app.row,app.col) == 1
                app.MISSLamp.Enable = 'on';
                
            end
            if app.hitormiss(app.row,app.col) == 2
                app.HITLamp.Enable = 'on';
            end
            if app.nearmiss(app.row, app.col) == 2
                app.NEARMISSLamp.Enable = 'on';
            end
            if app.nearmiss(app.row,app.col) == 1
                app.FARMISSLamp.Enable = 'on';
            end
            if app.hitormiss(app.row,app.col) == 2
                app.FARMISSLamp.Enable = 'off';
            end
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
           if app.BLock == 0
               return
           end
            app.HITLamp.Enable = 'off';
            app.MISSLamp.Enable = 'off';
            app.NEARMISSLamp.Enable = 'off';
            app.FARMISSLamp.Enable = 'off';
            if app.row +1 < 4
                app.board = zeros([3,3]);
                app.row = app.row + 1;
                app.board(app.row,app.col) = 1;
                UpdateBoard(app.a,app.pins,app.board);
            end
            if app.hitormiss(app.row,app.col) == 1
                app.MISSLamp.Enable = 'on';
                
            end
            if app.hitormiss(app.row,app.col) == 2
                app.HITLamp.Enable = 'on';
            end
            if app.nearmiss(app.row, app.col) == 2
                app.NEARMISSLamp.Enable = 'on';
            end
            if app.nearmiss(app.row,app.col) == 1
                app.FARMISSLamp.Enable = 'on';
            end
            if app.hitormiss(app.row,app.col) == 2
                app.FARMISSLamp.Enable = 'off';
            end
        end

        % Button pushed function: LEFTButton
        function LEFTButtonPushed(app, event)
            if app.BLock == 0
                return
            end
            app.HITLamp.Enable = 'off';
            app.MISSLamp.Enable = 'off';
            app.NEARMISSLamp.Enable = 'off';
            app.FARMISSLamp.Enable = 'off';
            if app.col - 1 > 0
                app.board = zeros([3,3]);
                app.col = app.col - 1;
                app.board(app.row,app.col) = 1;
                UpdateBoard(app.a,app.pins,app.board);
            end
            if app.hitormiss(app.row,app.col) == 1
                app.MISSLamp.Enable = 'on';
                
            end
            if app.hitormiss(app.row,app.col) == 2
                app.HITLamp.Enable = 'on';
            end
            if app.nearmiss(app.row, app.col) == 2
                app.NEARMISSLamp.Enable = 'on';
            end
            if app.nearmiss(app.row,app.col) == 1
                app.FARMISSLamp.Enable = 'on';
            end
            if app.hitormiss(app.row,app.col) == 2
                app.FARMISSLamp.Enable = 'off';
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.a = 0;
            app.a = arduino;
            app.pins = ["D12","D11","D10"; "D9","D8","D7"; "D6","D5","D4"];
            app.board = zeros([3,3]);
            if app.NumberofTorpedoesSpinner.Value < app.NumberofShipsSpinner.Value
                app.WARNUNGTextArea.Value = 'INSUFFICIENT TORPEDO COUNT FOR CONVOY SIZE';
                return
            end
            app.ocean = zeros([3,3]);
            app.firecontrol = zeros([3,3]);
            app.hitormiss = zeros([3,3]);
            app.nearmiss = zeros([3,3]);
            app.row = 2;
            app.col = 2;
            app.board(app.row,app.col) = 1;
            UpdateBoard(app.a,app.pins,app.board);
            app.HITLamp.Enable = 'off';
            app.MISSLamp.Enable = 'off';
            app.NEARMISSLamp.Enable = 'off';
            app.FARMISSLamp.Enable = 'off';
            app.x = 0;
            app.BLock = 1;
             
            while sum(sum(app.ocean)) < app.NumberofShipsSpinner.Value
            app.rr = randi(3);
            app.rc = randi(3);
            app.ocean(app.rr,app.rc) = 1;
            end
            app.SHIPSREMAININGEditField.Value = sum(sum(app.ocean));
            app.ATTEMPTSREMAININGEditField.Value = app.NumberofTorpedoesSpinner.Value;
            app.WARNUNGTextArea.Value = '';
            
        end

        % Button pushed function: HALTButton
        function HALTButtonPushed(app, event)
            
            app.board = zeros([3,3]);
            UpdateBoard(app.a,app.pins,app.board);
           
            app.HITLamp.Enable = 'off';
            app.MISSLamp.Enable = 'off';
            app.NEARMISSLamp.Enable = 'off';
            app.FARMISSLamp.Enable = 'off';
            app.SHIPSREMAININGEditField.Value = 0;
            app.ATTEMPTSREMAININGEditField.Value = 0;
          
            app.BLock = 0;
        end

        % Button pushed function: FEUERButton
        function FEUERButtonPushed(app, event)
        if app.BLock == 0
            return
        end


            app.HITLamp.Enable = 'off';
            app.MISSLamp.Enable = 'off';
            app.NEARMISSLamp.Enable = 'off';
            app.firecontrol(app.row,app.col) = 1;
            app.hitormiss(app.row,app.col) = (app.ocean(app.row,app.col)+app.firecontrol(app.row,app.col));
            app.nearmiss(app.row,app.col) = 1;
            if app.hitormiss(app.row,app.col) == 1
                app.MISSLamp.Enable = 'on';
                
            end
            if app.hitormiss(app.row,app.col) == 2
                app.HITLamp.Enable = 'on';
            end
            if app.row+1 < 4 && app.ocean(app.row+1,app.col) == 1
                app.nearmiss(app.row,app.col) = app.nearmiss(app.row,app.col) +1;
            end 
            if app.row-1 > 0 && app.ocean(app.row-1,app.col) == 1
                app.nearmiss(app.row,app.col) = app.nearmiss(app.row,app.col) +1;
            end
            if app.col+1 < 4 && app.ocean(app.row,app.col+1) ==1
                app.nearmiss(app.row,app.col) = app.nearmiss(app.row,app.col) +1;
            end
            if app.col-1 > 0 && app.ocean(app.row,app.col-1) == 1
                app.nearmiss(app.row,app.col) = app.nearmiss(app.row,app.col) +1;
            end
            if app.nearmiss(app.row, app.col) == 2
                app.NEARMISSLamp.Enable = 'on';
            end
            if app.nearmiss(app.row,app.col) == 1
                app.FARMISSLamp.Enable = 'on';
            end
            if app.hitormiss(app.row,app.col) == 2
                app.FARMISSLamp.Enable = 'off';
            end
            app.SHIPSREMAININGEditField.Value = (sum(sum(app.ocean))-sum(app.hitormiss(:) == 2));
            app.ATTEMPTSREMAININGEditField.Value = (sum(sum(app.NumberofTorpedoesSpinner.Value))-sum(sum(app.firecontrol)));
            if app.SHIPSREMAININGEditField.Value == 0
                app.vic = [1,0,1;0,1,0;1,0,1];
                disp('Congratulations! You sent those Brits to the bottom of the sea!');
                while app.x < 5
                    UpdateBoard(app.a,app.pins,app.vic);
                    pause(1)
                    UpdateBoard(app.a,app.pins,zeros([3,3]));
                    pause(1)
                    app.x = app.x + 1;
                end
            end
            if app.ATTEMPTSREMAININGEditField.Value == 0
                disp('Some ships got away! The Wolf Pack goes hungry tonight...');
                app.def = [1,1,1;0,0,0;1,1,1];
                app.def2 = [0,0,0;1,1,1;0,0,0];
                while app.x < 5
                    UpdateBoard(app.a,app.pins,app.def);
                    pause(1)
                    UpdateBoard(app.a,app.pins,app.def2);
                    pause(1)
                    app.x = app.x + 1;
                end
                app.board = zeros([3,3]);
                UpdateBoard(app.a,app.pins,app.board);
            end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofTorpedoesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofTorpedoesSpinnerLabel.Text = 'Number of Torpedoes';
            app.NumberofTorpedoesSpinner = uispinner(app.UIFigure);
            app.NumberofTorpedoesSpinner.Limits = [1 9];
            app.NumberofTorpedoesSpinner.Value = 1;
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.FEUERButton = uibutton(app.UIFigure, 'push');
            app.FEUERButton.ButtonPushedFcn = createCallbackFcn(app, @FEUERButtonPushed, true);
            app.FEUERButton.Text = 'FEUER';
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.RIGHTButton = uibutton(app.UIFigure, 'push');
            app.RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHTButtonPushed, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.LEFTButton = uibutton(app.UIFigure, 'push');
            app.LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @LEFTButtonPushed, true);
            app.LEFTButton.Text = 'LEFT';
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Text = 'DOWN';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.HALTButton = uibutton(app.UIFigure, 'push');
            app.HALTButton.ButtonPushedFcn = createCallbackFcn(app, @HALTButtonPushed, true);
            app.HALTButton.Text = 'HALT';
            app.HITLampLabel = uilabel(app.UIFigure);
            app.HITLampLabel.Text = 'HIT';
            app.HITLamp = uilamp(app.UIFigure);
            app.MISSLampLabel = uilabel(app.UIFigure);
            app.MISSLampLabel.Text = 'MISS';
            app.MISSLamp = uilamp(app.UIFigure);
            app.EINSTELLUNGENLabel = uilabel(app.UIFigure);
            app.EINSTELLUNGENLabel.Text = 'EINSTELLUNGEN';
            app.ZUSTANDLabel = uilabel(app.UIFigure);
            app.ZUSTANDLabel.Text = 'ZUSTAND';
            app.SCHALTTAFELLabel = uilabel(app.UIFigure);
            app.SCHALTTAFELLabel.Text = 'SCHALTTAFEL';
            app.MOTORSTARTLabel = uilabel(app.UIFigure);
            app.MOTORSTARTLabel.Text = 'MOTORSTART';
            app.SHIPSREMAININGEditFieldLabel = uilabel(app.UIFigure);
            app.SHIPSREMAININGEditFieldLabel.Text = 'SHIPS REMAINING';
            app.SHIPSREMAININGEditField = uieditfield(app.UIFigure, 'numeric');
            app.ATTEMPTSREMAININGEditFieldLabel = uilabel(app.UIFigure);
            app.ATTEMPTSREMAININGEditFieldLabel.Text = 'ATTEMPTS REMAINING';
            app.ATTEMPTSREMAININGEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEARMISSLampLabel = uilabel(app.UIFigure);
            app.NEARMISSLampLabel.Text = 'NEAR MISS';
            app.NEARMISSLamp = uilamp(app.UIFigure);
            app.FARMISSLampLabel = uilabel(app.UIFigure);
            app.FARMISSLampLabel.Text = 'FAR MISS';
            app.FARMISSLamp = uilamp(app.UIFigure);
            app.WARNUNGTextAreaLabel = uilabel(app.UIFigure);
            app.WARNUNGTextAreaLabel.Text = 'WARNUNG';
            app.WARNUNGTextArea = uitextarea(app.UIFigure);
            app.WARNUNGTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Battleship

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end