classdef section4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        StartGameButton               matlab.ui.control.Button
        QuitGameButton                matlab.ui.control.Button
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        ShootButton                   matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        HITLampLabel                  matlab.ui.control.Label
        HITLamp                       matlab.ui.control.Lamp
        MISSLampLabel                 matlab.ui.control.Label
        MISSLamp                      matlab.ui.control.Lamp
        BATTLESHIPLabel               matlab.ui.control.Label
        MemoTextAreaLabel             matlab.ui.control.Label
        MemoTextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        a = arduino;
        pins = ["D12", "D11", "D10"; "D9", "D8", "D7"; "D6", "D5", "D4"];
        r
        c
        start
        loc
        ships = zeros(3)
        guesses = zeros(3)
        board = zeros(3)
    end


    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.board(2,2)=1;
            app.HITLamp.Enable = 'off';
            app.MISSLamp.Enable = 'off';
                if app.NumberofShipsSpinner.Value <= app.NumberofGuessesSpinner.Value
                    app.start = 1;
                    UpdateBoard(app.a,app.pins,app.board)
                    app.ShotsRemainingEditField.Value = app.NumberofGuessesSpinner.Value;
                    app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner.Value;
                    e = randperm(4);
                    f = app.NumberofGuessesSpinner.Value;
                    for i = 1:f
                        if e == i
                            app.board(app.r,app.c) = 1;
                        end
                    end
                elseif app.NumberofShipsSpinner.Value > app.NumberofGuessesSpinner.Value
                   app.MemoTextArea.Value = 'Number of ships is greater than the number of guesses. Try again!';
                end
        end

        % Button pushed function: QuitGameButton
        function QuitGameButtonPushed(app, event)
            app.board(:,:) = 0;
            UpdateBoard(app.a,app.pins,app.board)
            close(app.UIFigure)
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            [app.r,app.c] = find(app.board==1);
            if app.start == 1
                if app.r ~= 0
                    if app.r - 1 ~= 0
                        app.board(app.r-1,app.c) = 1;
                        UpdateBoard(app.a,app.pins,app.board);
                        app.board(app.r,app.c) = 0;
                        UpdateBoard(app.a,app.pins,app.board);
                    end
                end
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            [app.r,app.c] = find(app.board==1);
            if app.start == 1
                if app.r ~= 4
                    if app.r + 1 ~= 4
                        app.board(app.r+1,app.c) = 1;
                        UpdateBoard(app.a,app.pins,app.board);
                        app.board(app.r,app.c) = 0;
                        UpdateBoard(app.a,app.pins,app.board);
                    end
                end
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            [app.r,app.c] = find(app.board==1);
            if app.start == 1
                if app.c ~= 0
                    if app.c - 1 ~= 0
                        app.board(app.r,app.c-1) = 1;
                        UpdateBoard(app.a,app.pins,app.board);
                        app.board(app.r,app.c) = 0;
                        UpdateBoard(app.a,app.pins,app.board);
                    end
                end
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            [app.r,app.c] = find(app.board==1);
            if app.start == 1
                if app.c ~= 4
                    if app.c + 1 ~= 4
                        app.board(app.r,app.c+1) = 1;
                        UpdateBoard(app.a,app.pins,app.board);
                        app.board(app.r,app.c) = 0;
                        UpdateBoard(app.a,app.pins,app.board);
                    end
                end
            end
        end

        % Button pushed function: ShootButton
        function ShootButtonPushed(app, event)
            if app.start == 1 % Hit
                if app.ships(app.r,app.c) == 1
                    app.HITLamp.Enable = 'on';
                    if app.guesses(app.r,app.c) == 0
                        app.ShotsRemainingEditField = app.ShotsRemainingEditField.Value - 1;
                        app.ShipsRemainingEditField.Value = app.ShipsRemainingEditField.Value - 1;
                        app.guesses(app.r,app.c) = 1;
                    end
                end
                
                if app.ships(app.r,app.c) == 0 % Miss
                    app.guesses(app.r,app.c) = 0;
                    app.MISSLamp.Enable = 'on';
                    app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value - 1;
                    app.guesses(app.r,app.c) = 1;
                end
            end
            
            if app.ShipsRemainingEditField.Value == 0 % Victory Sequence
                app.MemoTextArea.Value = 'Victory! You are the G.O.A.T.';
                allon = ones(3);
                alloff = zeros(3);
                
                for v = 1:1:2
                    UpdateBoard(app.a,app.pins,allon)
                    pause(1)
                    UpdateBoard(app.a,app.pins,alloff)
                    pause(1)
                end
                
                app.start = 0;
                
            elseif app.ShotsRemainingEditField.Value == 0 % Defeat Sequence
                app.MemoTextArea.Value = 'Defeat! You have dishonored your loved ones.';
                for w = 1:1:2
                    writeDigitalPin(app.a,'D12',1)
                    writeDigitalPin(app.a,'D11',1)
                    writeDigitalPin(app.a,'D10',1)
                    writeDigitalPin(app.a,'D9',1)
                    writeDigitalPin(app.a,'D8',1)
                    writeDigitalPin(app.a,'D7',1)
                    writeDigitalPin(app.a,'D6',1)
                    pause(1)
                    writeDigitalPin(app.a,'D12',0)
                    writeDigitalPin(app.a,'D11',0)
                    writeDigitalPin(app.a,'D10',0)
                    writeDigitalPin(app.a,'D9',0)
                    writeDigitalPin(app.a,'D8',0)
                    writeDigitalPin(app.a,'D7',0)
                    writeDigitalPin(app.a,'D6',0)
                    pause(1)
                end
                %app.start = 0;
            end
        end

        % Value changed function: ShipsRemainingEditField
        function ShipsRemainingEditFieldValueChanged(app, event)
            value = app.ShipsRemainingEditField.Value; % Location of ships
            m = 3;
            n = 3;
            f = value/9;
            app.guesses = zeros(m,n);
            k = round(f*m*n);
            app.ships = result(randperm(m*n,k));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Value = 1;
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.QuitGameButton = uibutton(app.UIFigure, 'push');
            app.QuitGameButton.ButtonPushedFcn = createCallbackFcn(app, @QuitGameButtonPushed, true);
            app.QuitGameButton.Text = 'Quit Game';
            app.ShotsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShotsRemainingEditFieldLabel.Text = 'Shots Remaining';
            app.ShotsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShotsRemainingEditField.Limits = [0 9];
            app.ShipsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShipsRemainingEditField.Limits = [0 4];
            app.ShipsRemainingEditField.ValueChangedFcn = createCallbackFcn(app, @ShipsRemainingEditFieldValueChanged, true);
            app.ShootButton = uibutton(app.UIFigure, 'push');
            app.ShootButton.ButtonPushedFcn = createCallbackFcn(app, @ShootButtonPushed, true);
            app.ShootButton.Text = {'Shoot'; ''};
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.HITLampLabel = uilabel(app.UIFigure);
            app.HITLampLabel.Text = 'HIT';
            app.HITLamp = uilamp(app.UIFigure);
            app.MISSLampLabel = uilabel(app.UIFigure);
            app.MISSLampLabel.Text = 'MISS';
            app.MISSLamp = uilamp(app.UIFigure);
            app.BATTLESHIPLabel = uilabel(app.UIFigure);
            app.BATTLESHIPLabel.Text = 'BATTLESHIP';
            app.MemoTextAreaLabel = uilabel(app.UIFigure);
            app.MemoTextAreaLabel.Text = 'Memo';
            app.MemoTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = section4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end