classdef Battleship < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ShipsSpinnerLabel             matlab.ui.control.Label
        ShipsSpinner                  matlab.ui.control.Spinner
        GuessesSpinnerLabel           matlab.ui.control.Label
        GuessesSpinner                matlab.ui.control.Spinner
        STARTGAMEButton               matlab.ui.control.Button
        UPButton                      matlab.ui.control.Button
        DOWNButton                    matlab.ui.control.Button
        LEFTButton                    matlab.ui.control.Button
        RIGHTButton                   matlab.ui.control.Button
        QUITGAMEButton                matlab.ui.control.Button
        RemainingShipsEditFieldLabel  matlab.ui.control.Label
        RemainingShips                matlab.ui.control.NumericEditField
        RemainingShotsEditFieldLabel  matlab.ui.control.Label
        RemainingShots                matlab.ui.control.NumericEditField
        HITLampLabel                  matlab.ui.control.Label
        HITLamp                       matlab.ui.control.Lamp
        MISSLampLabel                 matlab.ui.control.Label
        MISSLamp                      matlab.ui.control.Lamp
        SELECTButton                  matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        CONTROLPANELLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        ard = arduino(); % Description
        pins = ["D12" "D11" "D10";"D9" "D8" "D7"; "D6" "D5" "D4"]; % Description
        board = [0 0 0; 0 0 0; 0 0 0];
        ships = [0 0 0; 0 0 0; 0 0 0];
        hitmiss = [0 0 0; 0 0 0; 0 0 0];
        rows = 2;
        cols = 2;
        GAMESTART = [0 0 0;0 0 0; 0 0 0] % Description
    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Button pushed function: STARTGAMEButton
        function STARTGAMEButtonPushed(app, event)
            app.board = [0 0 0; 0 0 0; 0 0 0]; % Description
            app.ships = [0 0 0; 0 0 0; 0 0 0];
            app.hitmiss = [0 0 0; 0 0 0; 0 0 0];
            app.GAMESTART(1,1) = 3;
            disp('Marcos E. Ibarra Montoya  ENGI 1331H - PROJECT 4- SECTION 4')
            disp('meibarramontoya@uh.edu  1587720')
            disp(' ')
            if app.GuessesSpinner.Value >= app.ShipsSpinner.Value
               randvals = randi(9000,[3 3]);
               app.RemainingShips.Value = app.ShipsSpinner.Value;
               app.RemainingShots.Value = app.GuessesSpinner.Value;
               for n = 1:app.ShipsSpinner.Value
                   [rowsship,colsship] = find(randvals==min(min(randvals)));
                   app.ships(rowsship,colsship) = 2;
                   randvals(rowsship,colsship) = 10000;
               end
                             
               app.cols = 2;
               app.rows = 2;
               app.board(app.rows,app.cols) = 1;
               UpdateBoard(app.ard,app.pins,app.board);
           end
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            if app.rows >1 && app.GAMESTART(1,1) == 3
                app.board = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.board(app.rows-1,app.cols) = 1;
                UpdateBoard(app.ard,app.pins,app.board);
                app.HITLamp.Enable='off';
                app.MISSLamp.Enable='off';
                app.rows = app.rows-1;
                if app.hitmiss(app.rows,app.cols) == 3
                    app.HITLamp.Enable='on';
                end
                if app.hitmiss(app.rows,app.cols) == -1
                    app.MISSLamp.Enable='on';
                end
            end
            
        end

        % Button pushed function: RIGHTButton
        function RIGHTButtonPushed(app, event)
            if app.cols < 3 && app.GAMESTART(1,1) == 3
                app.board = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.board(app.rows,app.cols+1) = 1;
                UpdateBoard(app.ard,app.pins,app.board);
                app.HITLamp.Enable='off';
                app.MISSLamp.Enable='off';
                app.cols = app.cols+1;
                if app.hitmiss(app.rows,app.cols) == 3
                    app.HITLamp.Enable='on';
                end
                if app.hitmiss(app.rows,app.cols) == -1
                    app.MISSLamp.Enable='on';
                end
            end
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            if app.rows < 3 && app.GAMESTART(1,1) == 3
                app.board = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.board(app.rows+1,app.cols) = 1;
                UpdateBoard(app.ard,app.pins,app.board);
                app.HITLamp.Enable='off';
                app.MISSLamp.Enable='off';
                app.rows = app.rows+1;
                if app.hitmiss(app.rows,app.cols) == 3
                    app.HITLamp.Enable='on';
                end
                if app.hitmiss(app.rows,app.cols) == -1
                    app.MISSLamp.Enable='on';
                end
            end
            
        end

        % Button pushed function: LEFTButton
        function LEFTButtonPushed(app, event)
            if app.cols > 1 && app.GAMESTART(1,1) == 3
                app.board = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.board(app.rows,app.cols-1) = 1;
                UpdateBoard(app.ard,app.pins,app.board);
                app.HITLamp.Enable='off';
                app.MISSLamp.Enable='off';
                app.cols = app.cols-1;
                if app.hitmiss(app.rows,app.cols) == 3
                    app.HITLamp.Enable='on';
                end
                if app.hitmiss(app.rows,app.cols) == -1
                    app.MISSLamp.Enable='on';
                end
            end
        end

        % Button pushed function: QUITGAMEButton
        function QUITGAMEButtonPushed(app, event)
            app.board = [0 0 0; 0 0 0; 0 0 0];
            app.ships = [0 0 0; 0 0 0; 0 0 0];
            app.hitmiss = [0 0 0; 0 0 0; 0 0 0];
            UpdateBoard(app.ard,app.pins,app.board);
            app.HITLamp.Enable='off';
            app.MISSLamp.Enable='off';
            app.cols = 2;
            app.rows = 2;
            app.GAMESTART = [0 0 0; 0 0 0; 0 0 0];
            app.GAMESTART(1,1) = 0; 
        end

        % Button pushed function: SELECTButton
        function SELECTButtonPushed(app, event)
            if app.hitmiss(app.rows,app.cols) == 0 && app.GAMESTART(1,1) == 3
               app.RemainingShots.Value = app.RemainingShots.Value-1;
            end
            if app.ships(app.rows,app.cols) == 2 && app.GAMESTART(1,1) == 3
               app.RemainingShips.Value = app.RemainingShips.Value-1;
               app.ships(app.rows,app.cols) = -2;
               app.HITLamp.Enable='on';
               app.hitmiss(app.rows,app.cols) =3;
            elseif app.ships(app.rows,app.cols)==0 && app.GAMESTART(1,1) == 3
               app.MISSLamp.Enable='on';
               app.hitmiss(app.rows,app.cols) =-1;
            end

            if app.RemainingShips.Value == 0 && app.GAMESTART(1,1) == 3
                app.GAMESTART(1,1) = 2;
                app.board = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.board(1,1) =1
                app.board(1,2)=1
                app.board(1,3)=1
                UpdateBoard(app.ard,app.pins,app.board);
                pause(1)
                app.board(2,1)=1
                app.board(2,2)=1
                app.board(2,3)=1
                UpdateBoard(app.ard,app.pins,app.board);
                pause(1)
                app.board(3,1)=1
                app.board(3,2)=1
                app.board(3,3)=1
                UpdateBoard(app.ard,app.pins,app.board);
                disp('VICTORY IS YOURS!')
                disp('Winning pattern is each row starting from the top will turn on with a 1 second pause in between')
                pause(3)
                app.board = [0 0 0; 0 0 0; 0 0 0];
                app.ships = [0 0 0; 0 0 0; 0 0 0];
                app.hitmiss = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.GAMESTART = [0 0 0; 0 0 0; 0 0 0];
                app.GAMESTART(1,1) = 0; 
                app.HITLamp.Enable='off';
                app.MISSLamp.Enable='off';
            elseif app.RemainingShots.Value == 0 && app.RemainingShips.Value==0 && app.GAMESTART(1,1) == 3
                app.GAMESTART(1,1) = 2;
                app.board = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.board(1,1)=1
                app.board(1,2)=1
                app.board(1,3)=1
                UpdateBoard(app.ard,app.pins,app.board);
                pause(2)
                app.board(2,1)=1
                app.board(2,2)=1
                app.board(2,3)=1
                UpdateBoard(app.ard,app.pins,app.board);
                pause(2)
                app.board(3,1)=1
                app.board(3,2)=1
                app.board(3,3)=1
                UpdateBoard(app.ard,app.pins,app.board);%Winning pattern is each row starting from the top 
                disp('VICTORY IS YOURS!')               %will turn on with a 2 second pause in between        
                disp('Winning pattern is each row starting from the top will turn on with a 1 second pause in between')
                pause(3)
                app.board = [0 0 0; 0 0 0; 0 0 0];
                app.ships = [0 0 0; 0 0 0; 0 0 0];
                app.hitmiss = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.GAMESTART = [0 0 0; 0 0 0; 0 0 0];
                app.GAMESTART(1,1) = 0; 
                app.HITLamp.Enable='off';
                app.MISSLamp.Enable='off';
            elseif app.RemainingShots.Value == 0 && app.RemainingShips.Value ~=0 && app.GAMESTART(1,1) == 3
                app.GAMESTART(1,1) = 2;
                app.board = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.board(1,1) = 1;
                app.board(2,2) = 1;
                app.board(3,3) = 1;
                app.board(3,1) = 1;
                app.board(1,3) = 1;
                UpdateBoard(app.ard,app.pins,app.board); % Losing pattern will be an X on the LEDs
                disp('FAILURE. YOU HAVE LOST')
                disp('Losing pattern will be an X on the LEDs')
                pause(5)            
                app.board = [0 0 0; 0 0 0; 0 0 0];
                app.ships = [0 0 0; 0 0 0; 0 0 0];
                app.hitmiss = [0 0 0; 0 0 0; 0 0 0];
                UpdateBoard(app.ard,app.pins,app.board);
                app.GAMESTART = [0 0 0; 0 0 0; 0 0 0];
                app.GAMESTART(1,1) = 0; 
                app.HITLamp.Enable='off';
                app.MISSLamp.Enable='off';
            end
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ShipsSpinnerLabel = uilabel(app.UIFigure);
            app.ShipsSpinnerLabel.Text = 'Ships';
            app.ShipsSpinner = uispinner(app.UIFigure);
            app.ShipsSpinner.Limits = [1 4];
            app.ShipsSpinner.Value = 1;
            app.GuessesSpinnerLabel = uilabel(app.UIFigure);
            app.GuessesSpinnerLabel.Text = 'Guesses';
            app.GuessesSpinner = uispinner(app.UIFigure);
            app.GuessesSpinner.Limits = [1 9];
            app.GuessesSpinner.Value = 1;
            app.STARTGAMEButton = uibutton(app.UIFigure, 'push');
            app.STARTGAMEButton.ButtonPushedFcn = createCallbackFcn(app, @STARTGAMEButtonPushed, true);
            app.STARTGAMEButton.Text = {'START GAME'; ''};
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = {'UP'; ''};
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Text = 'DOWN';
            app.LEFTButton = uibutton(app.UIFigure, 'push');
            app.LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @LEFTButtonPushed, true);
            app.LEFTButton.Text = 'LEFT';
            app.RIGHTButton = uibutton(app.UIFigure, 'push');
            app.RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHTButtonPushed, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.QUITGAMEButton = uibutton(app.UIFigure, 'push');
            app.QUITGAMEButton.ButtonPushedFcn = createCallbackFcn(app, @QUITGAMEButtonPushed, true);
            app.QUITGAMEButton.Text = 'QUIT GAME';
            app.RemainingShipsEditFieldLabel = uilabel(app.UIFigure);
            app.RemainingShipsEditFieldLabel.Text = 'Remaining Ships';
            app.RemainingShips = uieditfield(app.UIFigure, 'numeric');
            app.RemainingShotsEditFieldLabel = uilabel(app.UIFigure);
            app.RemainingShotsEditFieldLabel.Text = 'Remaining Shots';
            app.RemainingShots = uieditfield(app.UIFigure, 'numeric');
            app.HITLampLabel = uilabel(app.UIFigure);
            app.HITLampLabel.Text = 'HIT';
            app.HITLamp = uilamp(app.UIFigure);
            app.HITLamp.Enable = 'off';
            app.MISSLampLabel = uilabel(app.UIFigure);
            app.MISSLampLabel.Text = 'MISS';
            app.MISSLamp = uilamp(app.UIFigure);
            app.MISSLamp.Enable = 'off';
            app.SELECTButton = uibutton(app.UIFigure, 'push');
            app.SELECTButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTButtonPushed, true);
            app.SELECTButton.Text = 'SELECT';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'BATTLESHIP'};
            app.CONTROLPANELLabel = uilabel(app.UIFigure);
            app.CONTROLPANELLabel.Text = {'CONTROL PANEL'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Battleship

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end