classdef section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotButton                matlab.ui.control.Button
        FunctionDropDownLabel     matlab.ui.control.Label
        FunctionDropDown          matlab.ui.control.DropDown
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel  matlab.ui.control.Label
        PhaseShiftEditField       matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
        xlabelEditFieldLabel      matlab.ui.control.Label
        xlabelEditField           matlab.ui.control.EditField
        ylabelEditFieldLabel      matlab.ui.control.Label
        ylabelEditField           matlab.ui.control.EditField
    end

    
    properties (Access = private)
        x = (0:.01:4*pi); % Description
    end
    

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if app.FunctionDropDown.Value == 'sin'
                y = app.AmplitudeEditField.Value*sin((app.FrequencyEditField.Value*app.x)+app.PhaseShiftEditField.Value);
                plot(app.UIAxes,app.x,y);
                title(app.UIAxes,app.TitleEditField.Value);
                xlabel(app.UIAxes,app.xlabelEditField.Value);
                ylabel(app.UIAxes,app.ylabelEditField.Value);
            else
                y = app.AmplitudeEditField.Value*cos((app.FrequencyEditField.Value*app.x)+app.PhaseShiftEditField.Value);
                plot(app.UIAxes,app.x,y);
                title(app.UIAxes,app.TitleEditField.Value);
                xlabel(app.UIAxes,app.xlabelEditField.Value);
                ylabel(app.UIAxes,app.ylabelEditField.Value);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = {'Plot'; ''};
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'sin', 'cos'};
            app.FunctionDropDown.Value = 'sin';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.Value = 1;
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 2;
            app.PhaseShiftEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShiftEditField.Value = 3;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.xlabelEditFieldLabel = uilabel(app.UIFigure);
            app.xlabelEditFieldLabel.Text = 'x-label';
            app.xlabelEditField = uieditfield(app.UIFigure, 'text');
            app.ylabelEditFieldLabel = uilabel(app.UIFigure);
            app.ylabelEditFieldLabel.Text = 'y-label';
            app.ylabelEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end