classdef Section4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        NumberofShipsSpinner_2Label   matlab.ui.control.Label
        NumberofShipsSpinner_2        matlab.ui.control.Spinner
        STARTButton                   matlab.ui.control.Button
        QUITButton                    matlab.ui.control.Button
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
        EditField_12                  matlab.ui.control.EditField
        RightButton                   matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        MISSLampLabel                 matlab.ui.control.Label
        MISSLamp                      matlab.ui.control.Lamp
        EditField_6                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        BATTLESHIPLabel               matlab.ui.control.Label
        SHOOTButton                   matlab.ui.control.Button
        HITLampLabel                  matlab.ui.control.Label
        HITLamp                       matlab.ui.control.Lamp
        EditField_14                  matlab.ui.control.EditField
        EditField_15                  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Property % Description
        a = arduino();
        pins = ["D12", "D11", "D10"; "D9", "D8", "D7"; "D6", "D5", "D4"]
        board = zeros(3:3);
        ships = zeros(3:3);
        guesses = zeros(3:3);
        guesses2 = zeros(3:3);
        lolvar1 = zeros(3:3);
        lolvar2 = zeros(3:3);
        nah = zeros(3);
        v1 = ones(3);
        v0 = zeros(3)
        ship_location = 0;
        start = 0
        shot = 0
        sh_ip = 0 
        var10 = false; 
        var11= true; 
        position = [1,1]
        x 
        y 
    end 
    
    methods (Access = private)
        
        function Position(app)
            app.board(app.position(1),app.position(2)) = 1; % Function updates position based on position property location 
            
        end
        
        function over(app) % function to implement if game is won 
            app.EditField_15.Value = "WINNER WINNER CHICKEN DINNER!" ;
            app.var10 = true ;
            disp("WINNER WINNER CHICKEN DINNER!")
        end
    end
    

    methods (Access = private)

        % Value changed function: NumberofGuessesSpinner
        function NumberofGuessesSpinnerValueChanged(app, event)

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
        app.board(2,2) = 1;
        if app.NumberofShipsSpinner_2.Value <= app.NumberofGuessesSpinner.Value
            app.start = 1;
            UpdateBoard(app.a, app.pins, app.board)
            app.ShotsRemainingEditField.Value = app.NumberofGuessesSpinner.Value;
            app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner_2.Value;
            q = randperm(4);
            qq = app.NumberofGuessesSpinner.Value;
            for i = 1:qq
                if q == i
                    app.board(x,y) = 1;
                end
            end
        elseif app.NumberofShipsSpinner_2.Value > app.NumberofGuessesSpinner.Value
            error("Please enter a number of ships less than your number of guesses")
        app.STARTButtonPushed.Value % is equal to 1 
        end 
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            app.a = arduino();
            app.board(:,:) = 0;
            UpdateBoard(app.a, app.pins, app.board)
            app.NumberofGuessesSpinner.Value = 0; 
            app.NumberofShipsSpinner_2.Value = 0;             
        end

        % Value changed function: ShotsRemainingEditField
        function ShotsRemainingEditFieldValueChanged(app, event)

        end

        % Value changed function: NumberofShipsSpinner_2
        function NumberofShipsSpinner_2ValueChanged(app, event)
        
        end

        % Callback function
        function EditField_7ValueChanged(app, event)
            
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
        [x,y] = find(app.board==1);
        if app.start == 1    
            if y>0
                if y-1 > 0
                    app.board(x,(y-1)) = 1;
                    UpdateBoard(app.a, app.pins, app.board);
                    app.board(x,y) = 0;
                    UpdateBoard(app.a, app.pins, app.board);
                end
            end
        end 
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
        [x,y] = find(app.board==1);
        if app.start == 1    
            if y < 4 
                if y+1 < 4 
                    app.board(x,(y+1)) = 1;
                    UpdateBoard(app.a, app.pins, app.board);
                    app.board(x,y) = 0;
                    UpdateBoard(app.a, app.pins, app.board);
                end
            end
        end 

        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
        [x,y] = find(app.board==1);
        if app.start == 1 
            if x > 0 
               if x-1 >0  
                    app.board((x-1),y) = 1;
                    UpdateBoard(app.a, app.pins, app.board);
                    app.board(x,y) = 0;
                    UpdateBoard(app.a, app.pins, app.board);
                end
            end
        end 


        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
        [x,y] = find(app.board==1);
        if app.start == 1     
            if x < 4  
               if x+1 < 4
                    app.board((x+1),y) = 1;
                    UpdateBoard(app.a, app.pins, app.board);
                    app.board(x,y) = 0;
                    UpdateBoard(app.a, app.pins, app.board);
                end
            end
        end 


        end

        % Value changed function: ShipsRemainingEditField
        function ShipsRemainingEditFieldValueChanged(app, event)
            value = app.ShipsRemainingEditField.Value;
            
        end

        % Button pushed function: SHOOTButton
        function SHOOTButtonPushed(app, event)
        if app.start == 1
            if app.ships(app.x,app.y) == 1 
                app.HITLamp.Enable = 'on';
                if app.guesses(app.x,app.y) == 0 
                    app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value-1 ;
                    app.ShipsRemainingEditField.Value = app.ShipsRemainingEditField.Value-1 ;
                    app.guesses(app.x,app.y) = 1;
                end 
            end 
            
            if app.ships(app.x,app.y) == 0
                app.guesses(app.x,app.y) = 0 ;
                app.MISSLamp.Enable = 'on';
                app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value - 1 ;
                app.guesses(app.r,app.c) = 1 ;
            end 
        end 
        if app.ShipsRemainingEditField == 0 
            app.EditField_15.Value = 'Winner winner chicken dinner!';
            
            for p = 1:1:2 
                UpdateBoard(app.a,app.pins, app.v0)
                pause(2)
                UpdateBoard(app.a,app.pins,app.v1)
            end 
            app.start = 0;
            
        elseif app.ShotsRemainingEditField.Value == 0 
            app.EditField_15.Value.Value = 'You lose, try again next time!'; 
            for ppp = 1:1:2 
                writeDigitalPin(app.a,'D12',1)
                writeDigitalPin(app.a,'D11',1)
                writeDigitalPin(app.a,'D10',1)
                writeDigitalPin(app.a,'D9',1)
                writeDigitalPin(app.a,'D8',1)
                writeDigitalPin(app.a,'D7',1)
                writeDigitalPin(app.a,'D6',1)
                pause(1) % Turning all off 
                writeDigitalPin(app.a,'D12',0)
                writeDigitalPin(app.a,'D11',0)
                writeDigitalPin(app.a,'D10',0)
                writeDigitalPin(app.a,'D9',0)
                writeDigitalPin(app.a,'D8',0)
                writeDigitalPin(app.a,'D7',0)
                writeDigitalPin(app.a,'D6',0)
            end 
        end             
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            value = app.EditField_15.Value;
            
        end

        % Value changing function: EditField_15
        function EditField_15ValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses ';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [0 9];
            app.NumberofGuessesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofGuessesSpinnerValueChanged, true);
            app.NumberofShipsSpinner_2Label = uilabel(app.UIFigure);
            app.NumberofShipsSpinner_2Label.Text = 'Number of Ships';
            app.NumberofShipsSpinner_2 = uispinner(app.UIFigure);
            app.NumberofShipsSpinner_2.Limits = [0 4];
            app.NumberofShipsSpinner_2.ValueChangedFcn = createCallbackFcn(app, @NumberofShipsSpinner_2ValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.QUITButton = uibutton(app.UIFigure, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = 'SETTINGS';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.Value = 'MISSION STATUS';
            app.ShipsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShipsRemainingEditField.Limits = [0 4];
            app.ShipsRemainingEditField.ValueChangedFcn = createCallbackFcn(app, @ShipsRemainingEditFieldValueChanged, true);
            app.ShotsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShotsRemainingEditFieldLabel.Text = 'Shots Remaining';
            app.ShotsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShotsRemainingEditField.Limits = [0 9];
            app.ShotsRemainingEditField.ValueChangedFcn = createCallbackFcn(app, @ShotsRemainingEditFieldValueChanged, true);
            app.EditField_12 = uieditfield(app.UIFigure, 'text');
            app.EditField_12.Value = 'CONTROLS';
            app.MISSLampLabel = uilabel(app.UIFigure);
            app.MISSLampLabel.Text = 'MISS';
            app.MISSLamp = uilamp(app.UIFigure);
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.BATTLESHIPLabel = uilabel(app.UIFigure);
            app.BATTLESHIPLabel.Text = 'BATTLESHIP';
            app.EditField_14 = uieditfield(app.UIFigure, 'text');
            app.EditField_14.Value = 'CONTROLS';
            app.HITLampLabel = uilabel(app.UIFigure);
            app.HITLampLabel.Text = 'HIT';
            app.HITLamp = uilamp(app.UIFigure);
            app.SHOOTButton = uibutton(app.UIFigure, 'push');
            app.SHOOTButton.ButtonPushedFcn = createCallbackFcn(app, @SHOOTButtonPushed, true);
            app.SHOOTButton.Text = 'SHOOT';
            app.EditField_15 = uieditfield(app.UIFigure, 'text');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_15.ValueChangingFcn = createCallbackFcn(app, @EditField_15ValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Section4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end