classdef section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        DropDownLabel             matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel  matlab.ui.control.Label
        PhaseShiftEditField       matlab.ui.control.NumericEditField
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
        XAxisLabelEditFieldLabel  matlab.ui.control.Label
        XAxisLabelEditField       matlab.ui.control.EditField
        YAxisLabelEditFieldLabel  matlab.ui.control.Label
        YAxisLabelEditField       matlab.ui.control.EditField
    end


    methods (Access = private)

        % Callback function: DropDown, PlotButton
        function PlotButtonPushed(app, event)
            a = app.AmplitudeEditField.Value;
            b = app.FrequencyEditField.Value;
            c = app.PhaseShiftEditField.Value;
            x = linspace(0,4*pi,500);
            if app.DropDown.Value == 'sin'
                y = a*sin(b*x+c);
                    plot(app.UIAxes,x,y);
            else y = a*cos(b*x+c);
                plot(app.UIAxes,x,y)
            end   
app.UIAxes.Title.String = app.TitleEditField.Value
app.UIAxes.XLabel.String = app.XAxisLabelEditField.Value
app.UIAxes.YLabel.String = app.YAxisLabelEditField.Value

            
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;

        end

        % Value changed function: XAxisLabelEditField
        function XAxisLabelEditFieldValueChanged2(app, event)
            value = app.XAxisLabelEditField.Value;
            
        end

        % Value changed function: YAxisLabelEditField
        function YAxisLabelEditFieldValueChanged2(app, event)
            value = app.YAxisLabelEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'sin', 'cos'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.DropDown.Value = 'cos';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShiftEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift ';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.XAxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.XAxisLabelEditFieldLabel.Text = 'X-Axis Label';
            app.XAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.XAxisLabelEditField.ValueChangedFcn = createCallbackFcn(app, @XAxisLabelEditFieldValueChanged2, true);
            app.YAxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisLabelEditFieldLabel.Text = 'Y-Axis Label';
            app.YAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YAxisLabelEditField.ValueChangedFcn = createCallbackFcn(app, @YAxisLabelEditFieldValueChanged2, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end