classdef battlsheet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        StartGameButton               matlab.ui.control.Button
        QuitGameButton                matlab.ui.control.Button
        ShotsRemainingEditFieldLabel  matlab.ui.control.Label
        ShotsRemainingEditField       matlab.ui.control.NumericEditField
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        LeftButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        UpButton                      matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        FireButton                    matlab.ui.control.Button
        HitLampLabel                  matlab.ui.control.Label
        HitLamp                       matlab.ui.control.Lamp
        MissLampLabel                 matlab.ui.control.Label
        MissLamp                      matlab.ui.control.Lamp
        GAMEPARAMETERSLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Arty = arduino(); % Initializing Arduino
        Cursor = zeros(3); %Creating Matrix for Cursor Location % Description
        Attempted = zeros(3); %Creating Matrix for Attempted Shots
        Sea = zeros(3); %Creating 3x3 matrix to represent Ships
        Pins = ["D12" "D11" "D10";"D9" "D8" "D7";"D6" "D5" "D4"]; %Creating Pins Matrix for LEDs
    end

    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            set(app.NumberofShipsSpinner,'Enable','off'); %Disabling Ship Spinner
            set(app.NumberofGuessesSpinner,'Enable','off'); %Disabling Guess Spineer
            set(app.ShipsRemainingEditField,'Enable','on'); %Enabling Ships Info Field
            set(app.ShotsRemainingEditField,'Enable','on'); %Enabling Shots Info Field
            app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner.Value; %Displaying Ships on Info Field
            if app.NumberofGuessesSpinner.Value < app.NumberofShipsSpinner.Value %If Guesses < Ships for Spinner
                app.ShotsRemainingEditField.Value = app.NumberofShipsSpinner.Value; %Set Guesses = Ships
            else
                app.ShotsRemainingEditField.Value = app.NumberofGuessesSpinner.Value; %Displaying Guesses on Info Field
            end
            Count = 0; %Creating a Count
            while Count < app.ShipsRemainingEditField.Value %While Loop for randomizing ship locations for amount of ships
                Ind = randi(9); %Random number 1 to 9 for ship location
                if app.Sea(Ind) == 0 %If there is no ship there
                    app.Sea(Ind) = 1; %Add a ship there
                    Count = Count + 1; %Add 1 to the count
                end
            end
            app.Cursor(5) = 1; %Setting Cursor to start in middle position
            writeDigitalPin(app.Arty,app.Pins(5),1); %Initializing Cursor LED
            set(app.StartGameButton,'Enable','off'); %Disabling Start Game Button
            set(app.QuitGameButton,'Enable','on'); %Enabling Quit Game Button
            set(app.UpButton,'Enable','on'); set(app.DownButton,'Enable','on'); set(app.LeftButton,'Enable','on'); %Enabling Up,Down,Left,Right,Fire Buttons
            set(app.LeftButton,'Enable','on'); set(app.RightButton,'Enable','on'); set(app.FireButton,'Enable','on');
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.Cursor(2) == 1 %Checking if the cursor is in the second position
                app.Cursor(2) = 0; %Moving the cursor from the second position
                app.Cursor(1) = 1; %...to the first position
                writeDigitalPin(app.Arty,app.Pins(2),0); %Updating Cursor on LED Board
                writeDigitalPin(app.Arty,app.Pins(1),1); %Updating Cursor on LED Board
                set(app.UpButton,'Enable','off'); %Disabling the Up Button
            elseif app.Cursor(3) == 1
                app.Cursor(3) = 0;
                app.Cursor(2) = 1;
                writeDigitalPin(app.Arty,app.Pins(3),0);
                writeDigitalPin(app.Arty,app.Pins(2),1);
                set(app.DownButton,'Enable','on');
            elseif app.Cursor(5) == 1
                app.Cursor(5) = 0;
                app.Cursor(4) = 1;
                writeDigitalPin(app.Arty,app.Pins(5),0);
                writeDigitalPin(app.Arty,app.Pins(4),1);
                set(app.UpButton,'Enable','off');
            elseif app.Cursor(6) == 1
                app.Cursor(6) = 0;
                app.Cursor(5) = 1;
                writeDigitalPin(app.Arty,app.Pins(6),0);
                writeDigitalPin(app.Arty,app.Pins(5),1);
                set(app.DownButton,'Enable','on');
            elseif app.Cursor(8) == 1
                app.Cursor(8) = 0;
                app.Cursor(7) = 1;
                writeDigitalPin(app.Arty,app.Pins(8),0);
                writeDigitalPin(app.Arty,app.Pins(7),1);
                set(app.UpButton,'Enable','off');
            elseif app.Cursor(9) == 1
                app.Cursor(9) = 0;
                app.Cursor(8) = 1;
                writeDigitalPin(app.Arty,app.Pins(9),0);
                writeDigitalPin(app.Arty,app.Pins(8),1);
                set(app.DownButton,'Enable','on');
            end
            Location = find(app.Cursor); %Finds the location of the cursor as a number 1 to 9
            if app.Attempted(Location) == 1 %If the space has been shot before
                set(app.FireButton,'Enable','off'); %Turn off the Fire button
                if app.Sea(Location) == 1 %If the space had a ship
                    set(app.HitLamp,'Enable','on'); %Turn on the hit lamp
                    set(app.MissLamp,'Enable','off'); %Turn off the miss lamp
                elseif app.Sea(Location) == 0 %If the space had noship
                    set(app.HitLamp,'Enable','off'); %Turn off the hit lamp
                    set(app.MissLamp,'Enable','on'); %Turn on the miss lamp
                end
            elseif app.Attempted(Location) == 0 %If the space has not been shot before
                set(app.FireButton,'Enable','on'); %Turn on the Fire button
                set(app.HitLamp,'Enable','off'); %Turn off the hit lamp
                set(app.MissLamp,'Enable','off'); %Turn off the miss lamp
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if app.Cursor(1) == 1
                app.Cursor(1) = 0;
                app.Cursor(2) = 1;
                writeDigitalPin(app.Arty,app.Pins(1),0);
                writeDigitalPin(app.Arty,app.Pins(2),1);
                set(app.UpButton,'Enable','on');
            elseif app.Cursor(2) == 1
                app.Cursor(2) = 0;
                app.Cursor(3) = 1;
                writeDigitalPin(app.Arty,app.Pins(2),0);
                writeDigitalPin(app.Arty,app.Pins(3),1);
                set(app.DownButton,'Enable','off');
            elseif app.Cursor(4) == 1
                app.Cursor(4) = 0;
                app.Cursor(5) = 1;
                writeDigitalPin(app.Arty,app.Pins(4),0);
                writeDigitalPin(app.Arty,app.Pins(5),1);
                set(app.UpButton,'Enable','on');
            elseif app.Cursor(5) == 1
                app.Cursor(5) = 0;
                app.Cursor(6) = 1;
                writeDigitalPin(app.Arty,app.Pins(5),0);
                writeDigitalPin(app.Arty,app.Pins(6),1);
                set(app.DownButton,'Enable','off');
            elseif app.Cursor(7) == 1
                app.Cursor(7) = 0;
                app.Cursor(8) = 1;
                writeDigitalPin(app.Arty,app.Pins(7),0);
                writeDigitalPin(app.Arty,app.Pins(8),1);
                set(app.UpButton,'Enable','on');
            elseif app.Cursor(8) == 1
                app.Cursor(8) = 0;
                app.Cursor(9) = 1;
                writeDigitalPin(app.Arty,app.Pins(8),0);
                writeDigitalPin(app.Arty,app.Pins(9),1);
                set(app.DownButton,'Enable','off');
            end
            Location = find(app.Cursor);
            if app.Attempted(Location) == 1
                set(app.FireButton,'Enable','off');
                if app.Sea(Location) == 1
                    set(app.HitLamp,'Enable','on');
                    set(app.MissLamp,'Enable','off');
                elseif app.Sea(Location) == 0
                    set(app.HitLamp,'Enable','off');
                    set(app.MissLamp,'Enable','on');
                end
            elseif app.Attempted(Location) == 0
                set(app.FireButton,'Enable','on');
                set(app.HitLamp,'Enable','off');
                set(app.MissLamp,'Enable','off');
            end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if app.Cursor(4) == 1
                app.Cursor(4) = 0;
                app.Cursor(1) = 1;
                writeDigitalPin(app.Arty,app.Pins(4),0);
                writeDigitalPin(app.Arty,app.Pins(1),1);
                set(app.LeftButton,'Enable','off');
            elseif app.Cursor(5) == 1
                app.Cursor(5) = 0;
                app.Cursor(2) = 1;
                writeDigitalPin(app.Arty,app.Pins(5),0);
                writeDigitalPin(app.Arty,app.Pins(2),1);
                set(app.LeftButton,'Enable','off');
            elseif app.Cursor(6) == 1
                app.Cursor(6) = 0;
                app.Cursor(3) = 1;
                writeDigitalPin(app.Arty,app.Pins(6),0);
                writeDigitalPin(app.Arty,app.Pins(3),1);
                set(app.LeftButton,'Enable','off');
            elseif app.Cursor(7) == 1
                app.Cursor(7) = 0;
                app.Cursor(4) = 1;
                writeDigitalPin(app.Arty,app.Pins(7),0);
                writeDigitalPin(app.Arty,app.Pins(4),1);
                set(app.RightButton,'Enable','on');
            elseif app.Cursor(8) == 1
                app.Cursor(8) = 0;
                app.Cursor(5) = 1;
                writeDigitalPin(app.Arty,app.Pins(8),0);
                writeDigitalPin(app.Arty,app.Pins(5),1);
                set(app.RightButton,'Enable','on');
            elseif app.Cursor(9) == 1
                app.Cursor(9) = 0;
                app.Cursor(6) = 1;
                writeDigitalPin(app.Arty,app.Pins(9),0);
                writeDigitalPin(app.Arty,app.Pins(6),1);
                set(app.RightButton,'Enable','on');
            end
            Location = find(app.Cursor);
            if app.Attempted(Location) == 1
                set(app.FireButton,'Enable','off');
                if app.Sea(Location) == 1
                    set(app.HitLamp,'Enable','on');
                    set(app.MissLamp,'Enable','off');
                elseif app.Sea(Location) == 0
                    set(app.HitLamp,'Enable','off');
                    set(app.MissLamp,'Enable','on');
                end
            elseif app.Attempted(Location) == 0
                set(app.FireButton,'Enable','on');
                set(app.HitLamp,'Enable','off');
                set(app.MissLamp,'Enable','off');
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if app.Cursor(1) == 1
                app.Cursor(1) = 0;
                app.Cursor(4) = 1;
                writeDigitalPin(app.Arty,app.Pins(1),0);
                writeDigitalPin(app.Arty,app.Pins(4),1);
                set(app.LeftButton,'Enable','on');
            elseif app.Cursor(2) == 1
                app.Cursor(2) = 0;
                app.Cursor(5) = 1;
                writeDigitalPin(app.Arty,app.Pins(2),0);
                writeDigitalPin(app.Arty,app.Pins(5),1);
                set(app.LeftButton,'Enable','on');
            elseif app.Cursor(3) == 1
                app.Cursor(3) = 0;
                app.Cursor(6) = 1;
                writeDigitalPin(app.Arty,app.Pins(3),0);
                writeDigitalPin(app.Arty,app.Pins(6),1);
                set(app.LeftButton,'Enable','on');
            elseif app.Cursor(4) == 1
                app.Cursor(4) = 0;
                app.Cursor(7) = 1;
                writeDigitalPin(app.Arty,app.Pins(4),0);
                writeDigitalPin(app.Arty,app.Pins(7),1);
                set(app.RightButton,'Enable','off');
            elseif app.Cursor(5) == 1
                app.Cursor(5) = 0;
                app.Cursor(8) = 1;
                writeDigitalPin(app.Arty,app.Pins(5),0);
                writeDigitalPin(app.Arty,app.Pins(8),1);
                set(app.RightButton,'Enable','off');
            elseif app.Cursor(6) == 1
                app.Cursor(6) = 0;
                app.Cursor(9) = 1;
                writeDigitalPin(app.Arty,app.Pins(6),0);
                writeDigitalPin(app.Arty,app.Pins(9),1);
                set(app.RightButton,'Enable','off');
            end
            Location = find(app.Cursor);
            if app.Attempted(Location) == 1
                set(app.FireButton,'Enable','off');
                if app.Sea(Location) == 1
                    set(app.HitLamp,'Enable','on');
                    set(app.MissLamp,'Enable','off');
                elseif app.Sea(Location) == 0
                    set(app.HitLamp,'Enable','off');
                    set(app.MissLamp,'Enable','on');
                end
            elseif app.Attempted(Location) == 0
                set(app.FireButton,'Enable','on');
                set(app.HitLamp,'Enable','off');
                set(app.MissLamp,'Enable','off');
            end
        end

        % Button pushed function: FireButton
        function FireButtonPushed(app, event)
            Location = find(app.Cursor); %Locate Cursor
            app.Attempted(Location) = 1; %Store that this spot has been shot
            if app.Sea(Location) == 1 %If the spot has a ship
                app.ShipsRemainingEditField.Value = app.ShipsRemainingEditField.Value-1; %Lower Ships by 1
                app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value-1; %Lower Shots by 1
                set(app.HitLamp,'Enable','on'); %Turning on hit lamp
            elseif app.Sea(Location) == 0 %If the spot has no ship
                app.ShotsRemainingEditField.Value = app.ShotsRemainingEditField.Value-1; %Lower Shots by 1
                set(app.MissLamp,'Enable','on'); %Turning on miss lamp
            end
            set(app.FireButton,'Enable','off'); %Turns off button after firing
            if app.ShipsRemainingEditField.Value == 0 %If all ships have been destroyed
                disp('Victory - You were successfully able to find all the ships'); %Victory Display Message
                set(app.UpButton,'Enable','off'); set(app.DownButton,'Enable','off'); set(app.LeftButton,'Enable','off'); %Disabling Up,Down,Left,Right,Fire Buttons
                set(app.LeftButton,'Enable','off'); set(app.RightButton,'Enable','off'); set(app.FireButton,'Enable','off');
                for c = 1:9
                    writeDigitalPin(app.Arty,app.Pins(c),0);
                end
                pause(0.2)
                Victory1 = [1 0 1;0 1 0;1 0 1];
                Victory2 = [0 1 0;1 0 1;0 1 0];
                for v = 1:3
                    for c = 1:9
                        writeDigitalPin(app.Arty,app.Pins(c),Victory1(c)); %Victory Light Sequence = Lights Flash in Diagonal Pattern
                    end
                    pause(.5);
                    for c = 1:9
                        writeDigitalPin(app.Arty,app.Pins(c),Victory2(c));
                    end
                    pause(.5);
                end
                for c = 1:9
                        writeDigitalPin(app.Arty,app.Pins(c),0);
                end
            elseif app.ShotsRemainingEditField.Value == 0 && app.ShipsRemainingEditField.Value > 0 %If all shots have been fired and at least one ship remains
                disp('Defeat - You were unable to find all of the ships'); %Defeat Display Message
                set(app.UpButton,'Enable','off'); set(app.DownButton,'Enable','off'); set(app.LeftButton,'Enable','off'); %Disabling Up,Down,Left,Right,Fire Buttons
                set(app.LeftButton,'Enable','off'); set(app.RightButton,'Enable','off'); set(app.FireButton,'Enable','off');
                for c = 1:9
                    writeDigitalPin(app.Arty,app.Pins(c),0); %Defeat Light Sequence = Lights Turn on and off from Left to Right
                end
                pause(0.2);
                for c = 1:3
                    writeDigitalPin(app.Arty,app.Pins(c),1);
                end
                pause(0.2);
                for c = 4:6
                    writeDigitalPin(app.Arty,app.Pins(c),1);
                end
                pause(0.2);
                for c = 7:9
                    writeDigitalPin(app.Arty,app.Pins(c),1);
                end
                pause(0.5);
                for c = 1:3
                    writeDigitalPin(app.Arty,app.Pins(c),0);
                end
                pause(0.2);
                for c = 4:6
                    writeDigitalPin(app.Arty,app.Pins(c),0);
                end
                pause(0.2);
                for c = 7:9
                    writeDigitalPin(app.Arty,app.Pins(c),0);
                end
                pause(0.5);
            end
        end

        % Button pushed function: QuitGameButton
        function QuitGameButtonPushed(app, event)
            for c = 1:9
                    writeDigitalPin(app.Arty,app.Pins(c),0);
            end
            set(app.NumberofShipsSpinner,'Enable','on'); %Enabling Ships Spineer
            set(app.NumberofGuessesSpinner,'Enable','on'); %Enabling Guess Spineer
            set(app.ShipsRemainingEditField,'Enable','off'); %Disabling Ships Info Field
            set(app.ShotsRemainingEditField,'Enable','off'); %Disabling Shots Info Field
            app.ShipsRemainingEditField.Value=0; app.ShotsRemainingEditField.Value=0; %Resetting Info Fields
            app.Cursor(:)=0; app.Sea(:)=0; app.Attempted(:)=0; %Resetting Matrices to 0
            set(app.StartGameButton,'Enable','on'); %Enabling Start Game Button
            set(app.QuitGameButton,'Enable','off'); %Disabling Quit Game Button
            set(app.UpButton,'Enable','off'); set(app.DownButton,'Enable','off'); set(app.LeftButton,'Enable','off'); %Disabling Up,Down,Left,Right,Fire Buttons
            set(app.LeftButton,'Enable','off'); set(app.RightButton,'Enable','off'); set(app.FireButton,'Enable','off');
            set(app.HitLamp,'Enable','off'); set(app.MissLamp,'Enable','off'); %Turn off Lamps
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 3;
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Value = 6;
            app.StartGameButton = uibutton(app.UIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.QuitGameButton = uibutton(app.UIFigure, 'push');
            app.QuitGameButton.ButtonPushedFcn = createCallbackFcn(app, @QuitGameButtonPushed, true);
            app.QuitGameButton.Enable = 'off';
            app.QuitGameButton.Text = 'Quit Game';
            app.ShotsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShotsRemainingEditFieldLabel.Text = 'Shots Remaining';
            app.ShotsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShotsRemainingEditField.Editable = 'off';
            app.ShipsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShipsRemainingEditField.Editable = 'off';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Enable = 'off';
            app.RightButton.Text = 'Right';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Enable = 'off';
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Enable = 'off';
            app.DownButton.Text = 'Down';
            app.FireButton = uibutton(app.UIFigure, 'push');
            app.FireButton.ButtonPushedFcn = createCallbackFcn(app, @FireButtonPushed, true);
            app.FireButton.Enable = 'off';
            app.FireButton.Text = 'Fire';
            app.HitLampLabel = uilabel(app.UIFigure);
            app.HitLampLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.UIFigure);
            app.HitLamp.Enable = 'off';
            app.MissLampLabel = uilabel(app.UIFigure);
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.UIFigure);
            app.MissLamp.Enable = 'off';
            app.GAMEPARAMETERSLabel = uilabel(app.UIFigure);
            app.GAMEPARAMETERSLabel.Text = 'GAME PARAMETERS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = battlsheet

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end