classdef Battleship_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SettingsLabel                 matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
        ImportantButtonsLabel         matlab.ui.control.Label
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        AttemptsRemainingEditFieldLabel  matlab.ui.control.Label
        AttemptsRemainingEditField    matlab.ui.control.NumericEditField
        MissionStatusLabel            matlab.ui.control.Label
        HitLabel                      matlab.ui.control.Label
        HitLamp                       matlab.ui.control.Lamp
        MissLampLabel                 matlab.ui.control.Label
        MissLamp                      matlab.ui.control.Lamp
        UpButton                      matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        ControlPanelLabel             matlab.ui.control.Label
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        MEMOTextAreaLabel             matlab.ui.control.Label
        MEMOTextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        ard=arduino()
        pins=["D12","D11","D10";"D9","D8","D7";"D6","D5","D4"]
        rows
        columns
        start=0
        loc
        ship_lower
        guess_lower
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.start=1;
            app.HitLamp.Enable='off';
            app.MissLamp.Enable='off'
            app.guess_lower=zeros(3);
            app.rows=2;
            app.columns=2;
            app.loc=app.pins(app.rows,app.columns);
            if app.NumberofGuessesSpinner.Value < app.NumberofShipsSpinner.Value
                app.MEMOTextArea.Value='Number of ships exceeds the number of guesses: NOT ALLOWED!'
            end
            
            if app.NumberofGuessesSpinner.Value > app.NumberofShipsSpinner.Value
                app.start=1;
            end
            
            if app.start==1
                app.ShipsRemainingEditField.Value=app.NumberofShipsSpinner.Value;
                app.AttemptsRemainingEditField.Value=app.NumberofGuessesSpinner.Value;
                writeDigitalPin(app.ard,app.loc,1)
                app.ship_lower=zeros(3,3)
                
            end
            
            ships_left=app.NumberofShipsSpinner.Value;
            n=0
            for t=1:1:1000
                if t<n
                    app.ship_lower(randi(3),randi(3))=1;
                    [r,c]=find(app.ship_lower==1);
                    n=length(r);
                end
                
            end
        end

        % Callback function
        function QuitButtonPushed(app, event)
            app.start=0;
            begin_off=zeros(3);
            UpdateBoard(app.ard,app.pins,begin_off);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if app.start==1
                if app.ship_lower(app.row,app.col)==1
                    app.HitLamp.Enable='on';
                    
                    if app.guess_lower(app.rows,app.columns)==0
                        app.AttemptsRemainingEditField=app.AttemptsRemainingEditField-1;
                        app.ShipsRemainingEditField.Value=app.ShipsRemainingEditField.Value-1;
                        app.guess_lower(app.rows,app.columns)=1;
                    end
                else
                    app.MissLamp.Enable='on';
                    
                    if app.guess_lower(app.rows,app.columns)==0
                        app.AttemptsRemainingEditField.Value=app.AttemptsRemainingEditField-1;
                        app.guess_lower(app.rows,app.columns)=1;
                    end
                end
                
                if app.ShipsRemainingEditField.Value==0
                    app.MEMOTextArea.Value=disp('YOU WINNN!!!:-)');
                    
                    on_all=ones(3);
                    off_all=zeros(3);
                    
                    for y=1:1:2
                        UpdateBoard(app.ard,app.pins,on_all)
                        pause(1)
                        UpdateBoard(app.ard,app.pins,off_all)
                    end
                elseif app.AttemptsRemainingEditField.Value ==0
                    app.MEMOTextArea.Value=disp('YOU LOST!!!:-(');
                    for t=1:1:2
                        UpdateBoard(app.ard,app.pins,on_all)
                    end
                    
                    app.start=0;
                end
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
                if app.start==1
                    app.HitLamp.Enable='off';
                    app.MissLamp.Enable='off';
                    if app.col==3
                        writeDigitalPin(app.ard,app.loc,1)
                    else
                        writeDigitalPin(app.ard,app.loc,0)
                        app.columns=app.columns+1;
                        app.loc=app.pins(app.ard,app.loc)
                        writeDigitalPin(app.ard,app.loc,1)
                    end
                end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
                if app.start==1
                    app.HitLamp.Enable='off';
                    app.Misslamp.Enable='off';
                    if app.rows==1
                        writeDigitalPin(app.ard,app.loc,1)
                    else
                        writeDigitalPin(app.ard,app.loc,0)
                        app.rows=app.rows-1;
                        app.loc=app.pins(app.rows,app.columns);
                        writeDigitalPin(app.ard,app.loc,1)
                    end
                end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
                if app.start==1
                    app.HitLamp.Enable='off'
                    app.MissLamp.Enable='off'
                    if app.columns==1
                        writeDigitalPin(app.ard,app.loc,1)
                    else
                        writeDigitalPin(app.ard,app.loc,0)
                        app.columns=app.columns-1;
                        app.loc=app.pins(app.rows,app.columns,1);
                        writeDigitalPin(app.ard,app.loc,1)
                    end
                end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
                if app.start==1
                    app.HitLamp.Enable='off';
                    app.Misslamp.Enable='off';
                    if app.rows==1
                        writeDigitalPin(app.ard,app.loc,1)
                    else
                        writeDigitalPin(app.ard,app.loc,0)
                        app.rows=app.rows+1;
                        app.loc=app.pins(app.rows,app.columns);
                        writeDigitalPin(app.ard,app.loc,1)
                    end
                end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.Text = 'Quit';
            app.ImportantButtonsLabel = uilabel(app.UIFigure);
            app.ImportantButtonsLabel.Text = 'Important Buttons';
            app.ShipsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.AttemptsRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.AttemptsRemainingEditFieldLabel.Text = 'Attempts Remaining';
            app.AttemptsRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.MissionStatusLabel = uilabel(app.UIFigure);
            app.MissionStatusLabel.Text = 'Mission Status';
            app.HitLabel = uilabel(app.UIFigure);
            app.HitLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.UIFigure);
            app.MissLampLabel = uilabel(app.UIFigure);
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.UIFigure);
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.NumberofShipsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.UIFigure);
            app.NumberofShipsSpinner.Limits = [1 4];
            app.NumberofShipsSpinner.Value = 1;
            app.NumberofGuessesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.UIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Value = 1;
            app.MEMOTextAreaLabel = uilabel(app.UIFigure);
            app.MEMOTextAreaLabel.Text = 'MEMO';
            app.MEMOTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Battleship_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end