classdef section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        DropDownLabel             matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        PhaseAngleEditFieldLabel  matlab.ui.control.Label
        PhaseAngleEditField       matlab.ui.control.NumericEditField
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
        XaxisLabelEditFieldLabel  matlab.ui.control.Label
        XaxisLabelEditField       matlab.ui.control.EditField
        YaxisLabelEditFieldLabel  matlab.ui.control.Label
        YaxisLabelEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        x=[0:0.1:4.*pi] % Description
    end
    

    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            A = app.AmplitudeEditField.Value
            B = app.FrequencyEditField.Value
            C = app.PhaseAngleEditField.Value
            if app.DropDown.Value == 'sin'
                y=A.*sin(B.*app.x+C);
                plot(app.UIAxes,app.x,y);
            else app.DropDown.Value == 'cos'
                y=A.*cos(B.*app.x+C);
                plot(app.UIAxes,app.x,y);
            end
            app.UIAxes.Title.String=app.TitleEditField.Value
            app.UIAxes.XLabel.String=app.XaxisLabelEditField.Value
            app.UIAxes.YLabel.String=app.YaxisLabelEditField.Value
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'sin', 'cos'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'sin';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.Value = 1;
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Value = 1;
            app.PhaseAngleEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseAngleEditFieldLabel.Text = 'Phase Angle';
            app.PhaseAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.Value = 'Graph';
            app.XaxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.XaxisLabelEditFieldLabel.Text = 'X-axis Label';
            app.XaxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.XaxisLabelEditField.Value = 'x-axis';
            app.YaxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YaxisLabelEditFieldLabel.Text = 'Y-axis Label';
            app.YaxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YaxisLabelEditField.Value = 'y-axis';
        end
    end

    methods (Access = public)

        % Construct app
        function app = section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end